classdef Lightsheet_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MotorStagePanel               matlab.ui.container.Panel
        DetmoveDropDownLabel          matlab.ui.control.Label
        DetmoveDropDown               matlab.ui.control.DropDown
        leftButton                    matlab.ui.control.Button
        rightButton                   matlab.ui.control.Button
        stepsizeEditFieldLabel        matlab.ui.control.Label
        stepsizeEditField             matlab.ui.control.NumericEditField
        ImageButton                   matlab.ui.control.Button
        rangeEditFieldLabel           matlab.ui.control.Label
        rangeEditField                matlab.ui.control.NumericEditField
        DetposEditFieldLabel          matlab.ui.control.Label
        DetposEditField               matlab.ui.control.NumericEditField
        zernikeButton                 matlab.ui.control.Button
        ThorlabcamPanel               matlab.ui.container.Panel
        ConnectCamButton              matlab.ui.control.Button
        DisconnectCamButton           matlab.ui.control.Button
        Exposure_msEditFieldLabel     matlab.ui.control.Label
        Exposure_msEditField          matlab.ui.control.NumericEditField
        ContrastEditFieldLabel        matlab.ui.control.Label
        ContrastEditField             matlab.ui.control.NumericEditField
        CamLampLabel                  matlab.ui.control.Label
        CamLamp                       matlab.ui.control.Lamp
        StartCamButton                matlab.ui.control.Button
        inUseLampLabel                matlab.ui.control.Label
        inUseLamp                     matlab.ui.control.Lamp
        StopCamButton                 matlab.ui.control.Button
        save_imageButton              matlab.ui.control.Button
        StatusPanel                   matlab.ui.container.Panel
        Stage_XEditFieldLabel         matlab.ui.control.Label
        Stage_XEditField              matlab.ui.control.NumericEditField
        Stage_YEditFieldLabel         matlab.ui.control.Label
        Stage_YEditField              matlab.ui.control.NumericEditField
        Stage_ZEditFieldLabel         matlab.ui.control.Label
        Stage_ZEditField              matlab.ui.control.NumericEditField
        LaserPanel                    matlab.ui.container.Panel
        Switch                        matlab.ui.control.Switch
        PowerEditFieldLabel           matlab.ui.control.Label
        PowerEditField                matlab.ui.control.NumericEditField
        galvocontrolPanel             matlab.ui.container.Panel
        G_XSliderLabel                matlab.ui.control.Label
        G_XSlider                     matlab.ui.control.Slider
        G_YSliderLabel                matlab.ui.control.Label
        G_YSlider                     matlab.ui.control.Slider
        G_XEditFieldLabel             matlab.ui.control.Label
        G_XEditField                  matlab.ui.control.NumericEditField
        G_YEditFieldLabel             matlab.ui.control.Label
        G_YEditField                  matlab.ui.control.NumericEditField
        HamamatsucamPanel             matlab.ui.container.Panel
        EnableButton                  matlab.ui.control.Button
        DisableButton                 matlab.ui.control.Button
        ExpEditFieldLabel             matlab.ui.control.Label
        ExpEditField                  matlab.ui.control.NumericEditField
        saving_PathEditFieldLabel     matlab.ui.control.Label
        saving_PathEditField          matlab.ui.control.EditField
        saving_DirButton              matlab.ui.control.Button
        Save_imgButton                matlab.ui.control.Button
        ImagecontrastLabel            matlab.ui.control.Label
        Imagecontrast                 matlab.ui.control.NumericEditField
        SampleStageStackimagingPanel  matlab.ui.container.Panel
        UpButton                      matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        InButton                      matlab.ui.control.Button
        OutButton                     matlab.ui.control.Button
        XY_stepDropDownLabel          matlab.ui.control.Label
        XY_stepDropDown               matlab.ui.control.DropDown
        Z_stepDropDownLabel           matlab.ui.control.Label
        Z_stepDropDown                matlab.ui.control.DropDown
        StartEditFieldLabel           matlab.ui.control.Label
        StartEditField                matlab.ui.control.NumericEditField
        EndEditFieldLabel             matlab.ui.control.Label
        EndEditField                  matlab.ui.control.NumericEditField
        SaveButton                    matlab.ui.control.Button
        SaveButton_2                  matlab.ui.control.Button
        StackimagingButton            matlab.ui.control.Button
        ZdistanceEditFieldLabel       matlab.ui.control.Label
        ZdistanceEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        parameters; % Description
        tlCameraSDK;
        tlCamera;
        serialNumbers;
        f; % image window
        myhandles;
        keepRunning;
        save_img;
        detLens;
        s561nm;
        galvox;
        galvoy;
        s;
        xy_step;
        z_step;
        mmc;
        dirname;
        imagecontrast_change;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('D:/lightsheet_test/lib_device'));
            addpath(genpath('D:/lightsheet_test/AfgCode'));
            addpath(genpath('D:/lightsheet_test/UsefulFun'));
            addpath(genpath('C:\Program Files\Meadowlark Optics\Blink 1920 HDMI\SDK'));
            addpath(genpath('C:\Users\Greenabaumlab\Downloads\zernike'));
            %app.parameters = evalin('base','controlParameters');
            % initialize moter
            portDetMotor = 'COM4';
            [app.detLens] = initMotor(portDetMotor);
            fprintf(app.detLens,['1PA','6']);
            % initialize laser
            portLaser = 'COM7';
            [app.s561nm] = initObis(portLaser);
            
            pause(5);
            disp('Initialize the laser');
            disp('Initialize the motor');
            fprintf(app.detLens,'1TP?');
            pos = fscanf(app.detLens);
            app.DetposEditField.Value = getpos(pos);
            app.save_img = false;
            app.imagecontrast_change = true;
            pathToCfgFile = 'C:\Micro-Manager-1.4\MMConfig_10_04_2022.cfg';
            app.mmc = InitiateHardware(pathToCfgFile);
            app.Stage_XEditField.Value = app.mmc.getXPosition();
            app.Stage_YEditField.Value = app.mmc.getYPosition();
            app.Stage_ZEditField.Value = app.mmc.getPosition('ZStage:Z:32');
            app.xy_step = 10;
            app.z_step = 10;
%             app.s = daq.createSession('mcc');
%             shutter = addAnalogOutputChannel(app.s,'Board0', 'Ao0', 'Voltage');
%             galvox = addAnalogOutputChannel(app.s,'Board0', 'Ao1', 'Voltage');
%             galvoy = addAnalogOutputChannel(app.s,'Board0', 'Ao2', 'Voltage');
%             app.galvox = 0;
%             app.galvoy = 0;
%             outputSingleScan(app.s,[0,0.13,0.05]);
%             loadlibrary('Blink_C_wrapper.dll', 'Blink_C_wrapper.h');
%             pause(5)
% 
%             lut_file = 'C:\Program Files\Meadowlark Optics\Blink 1920 HDMI\LUT Files\slm5504_at532.LUT';
% 
%            pause(5)
%             calllib('Blink_C_wrapper', 'Create_SDK');
% 
%              pause(5)
%          disp('Blink SDK was successfully constructed');
% 
% % Load the lookup table to the controller. Make sure that your com port is
% % correct. You can open Blink to validate the COM port. 
%           calllib('Blink_C_wrapper', 'Load_lut', lut_file);

% pause(5)
            %app.tlCameraSDK.Dispose;
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            stepSize = app.DetmoveDropDown.Value;
            fprintf(app.detLens,['1PR',num2str(stepSize)]);
            pause(0.5);
            fprintf(app.detLens,'1TP?');
            pos = fscanf(app.detLens);
            app.DetposEditField.Value = getpos(pos);
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            stepSize = app.DetmoveDropDown.Value;
            fprintf(app.detLens,['1PR-',num2str(stepSize)]);
            pause(0.5);
            fprintf(app.detLens,'1TP?');
            pos = fscanf(app.detLens);
            app.DetposEditField.Value = getpos(pos);
        end

        % Button pushed function: ConnectCamButton
        function ConnectCamButtonPushed(app, event)
            if app.CamLamp.Color(2) == 1
                disp('Camera is already connected.');
                return
            end
            NET.addAssembly([pwd, '\Thorlabs.TSI.TLCamera.dll']);
            disp('Dot NET assembly loaded.');
            app.tlCameraSDK = Thorlabs.TSI.TLCamera.TLCameraSDK.OpenTLCameraSDK;

            % Get serial numbers of connected TLCameras.
            app.serialNumbers = app.tlCameraSDK.DiscoverAvailableCameras;
            disp([num2str(app.serialNumbers.Count), ' camera is discovered.']);
            app.CamLamp.Color = [0 1 0];
%             app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
%             app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            
%             disp('Starting continuous image acquisition.');
%             app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
%             app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
%             app.tlCamera.Arm;
%             app.tlCamera.IssueSoftwareTrigger;
            
%             app.f = figure;
%             app.f.Position = [100 100 1024 1024];
%             set(app.f,'toolbar','figure');
%             hAxes = subplot(1,1,1);
%             app.myhandles = guihandles(app.f);
%             
%             app.keepRunning = true;
%             while app.keepRunning
%                 if isvalid(app.tlCamera) && app.tlCamera.NumberOfQueuedFrames > 0
%                     imageFrame = handles.tlCamera.GetPendingFrameOrNull;
%                     a= 1;
%                 end
%                 break;
%             end
        end

        % Button pushed function: DisconnectCamButton
        function DisconnectCamButtonPushed(app, event)
            if app.CamLamp.Color(2) == 0
                disp('Camera is already disconnected.');
                return;
            end
            if app.serialNumbers.Count == 0
                disp('no camera found.');
                return
            end
            
            disp([num2str(app.serialNumbers.Count),' camera is disconnected.'])
            app.tlCameraSDK.Dispose;
            delete(app.tlCameraSDK);
            %calllib('Blink_C_wrapper', 'Delete_SDK');
            %unloadlibrary('Blink_C_wrapper');
            app.CamLamp.Color = [1,0,0];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.StopCamButtonPushed(app);
            app.DisconnectCamButtonPushed(app);
            delete(app)
        end

        % Button pushed function: StartCamButton
        function StartCamButtonPushed(app, event)
            if app.CamLamp.Color(2) == 0
                disp('Camera is disconnected, Need to Connect Camera first.');
                return;
            end
            if app.inUseLamp.Color(2) == 1
                disp('Camera is in use, end acquistion first');
                return;
            end
            app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
            app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            maxPixelIntensity = double(2^app.tlCamera.BitDepth - 1);
            app.inUseLamp.Color = [0,1,0];
            
            app.f = figure;
            app.f.Position = [100 100 1024 1024];
            set(app.f,'toolbar','figure');
            hAxes = subplot(1,1,1);
            app.myhandles = guihandles(app.f);
            guidata(app.f,app.myhandles);
            app.myhandles.continue = true;
            a = 1;
            while app.myhandles.continue
                
                if (isvalid(app.tlCamera) && app.tlCamera.NumberOfQueuedFrames > 0)
                    
                    imageFrame = app.tlCamera.GetPendingFrameOrNull;
                    pause(0.1);
                    if ~isempty(imageFrame)
                        imageData = imageFrame.ImageData.ImageData_monoOrBGR;
                        imageHeight = imageFrame.ImageData.Height_pixels;
                        imageWidth = imageFrame.ImageData.Width_pixels;
                        imageData2D = reshape(uint16(imageData), [imageWidth, imageHeight]);
                        
                        if app.myhandles.continue == false
                            break;
                        end
                        
                        hImage = imshow(imageData2D,'Parent',hAxes,'DisplayRange',[0,app.ContrastEditField.Value]);
                        set(hImage,'CData',imageData2D);
                        
                        if app.save_img == true
                            app.save_img = false;
                            imwrite(imageData2D,'save_img/1.tiff');
                        end
                    end  
                end
            end
            
        end

        % Button pushed function: StopCamButton
        function StopCamButtonPushed(app, event)
            if app.inUseLamp.Color(2) == 0
                disp('Camera is already stopped.');
                return;
            end
            app.myhandles.continue = false;
            close(app.f);
            disp('Releasing the camera');
            app.inUseLamp.Color = [1,0,0];
            app.tlCamera.Disarm;
            app.tlCamera.Dispose;
            delete(app.tlCamera);
        end

        % Button pushed function: save_imageButton
        function save_imageButtonPushed(app, event)
            app.save_img = true;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if isequal(value,'On')
                fprintf(app.s561nm,'SOUR:AM:STAT ON');
            elseif isequal(value,'Off')
                fprintf(app.s561nm,'SOUR:AM:STAT OFF');
            end
            
        end

        % Value changed function: PowerEditField
        function PowerEditFieldValueChanged(app, event)
            value = app.PowerEditField.Value;
            if value >= 200
                value = 200;
            elseif value <= 0
                value = 1;
            end
            fprintf(app.s561nm,['SOUR:POW:LEV:IMM:AMPL ',num2str(value/1000)]);
            temp = fscanf(app.s561nm);
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            app.StopCamButtonPushed(app);
            
            if app.CamLamp.Color(2) == 0
                disp('Camera is disconnected, Need to Connect Camera first.');
                return;
            end
            if app.inUseLamp.Color(2) == 1
                disp('Camera needs to stop streaming first.');
                return;
            end
            if app.rangeEditField.Value < app.stepsizeEditField.Value
                disp('range need to be larger than step size.');
                return;
            end
            foldername = 'save_img';
            if ~exist(foldername)
                mkdir(foldername);
            end
            % start camera
            app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
            app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            
            positions = app.DetposEditField.Value - app.rangeEditField.Value:app.stepsizeEditField.Value:app.DetposEditField.Value + app.rangeEditField.Value;
            for i = 1:numel(positions)
                fprintf(app.detLens,['1PA',num2str(positions(i))]);
                pause(1);
                if i == 1
                    pause(1);
                end
                % get the pos
                fprintf(app.detLens,'1TP?');
                pos = fscanf(app.detLens);
                pos = getpos(pos);
                disp(pos);
                % grap one image
                imageFrame = app.tlCamera.GetPendingFrameOrNull;
                pause(0.1);
                imageData = imageFrame.ImageData.ImageData_monoOrBGR;
                imageHeight = imageFrame.ImageData.Height_pixels;
                imageWidth = imageFrame.ImageData.Width_pixels;
                imageData2D = reshape(uint16(imageData), [imageWidth, imageHeight]);
                imwrite(imageData2D,['save_img/test/',num2str(pos*100),'.tiff']);
            end
            fprintf(app.detLens,['1PA',num2str(app.DetposEditField.Value)]);
            pause(1);
            
            % close camera
            app.tlCamera.Disarm;
            app.tlCamera.Dispose;
            delete(app.tlCamera);
        end

        % Button pushed function: zernikeButton
        function zernikeButtonPushed(app, event)
            app.StopCamButtonPushed(app);
            
            if app.CamLamp.Color(2) == 0
                disp('Camera is disconnected, Need to Connect Camera first.');
                return;
            end
            if app.inUseLamp.Color(2) == 1
                disp('Camera needs to stop streaming first.');
                return;
            end
            if app.rangeEditField.Value < app.stepsizeEditField.Value
                disp('range need to be larger than step size.');
                return;
            end
            foldername = 'zern_img';
            if ~exist(foldername)
                mkdir(foldername);
            end
            % start camera
            app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
            app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            
            x=1:1:1152;
            x=x-576;
            lmd=561*10^-9;
             px=8*10^-6;
             f=12*10^-2;%19.99*10^-2;
             y=x;
             [X,Y]=meshgrid(x*px,y*px);

            shift=0.0*(X)/lmd;
            R=(X.^2+Y.^2);
             p=100;
             rqpf=(sqrt(R/(px.^2))/p).^4;
            lt=-2*pi*R/(lmd*f);
            lens=mod((lt),2*pi);
            lens1=uint8(lens*(255)/(2*pi));
             lens2=padarray(lens1,[384,0]);
            calllib('Blink_C_wrapper','Write_image', lens2,1);
            pause(0.1)
            imageFrame = app.tlCamera.GetPendingFrameOrNull;
                pause(0.1);
            imageData = imageFrame.ImageData.ImageData_monoOrBGR;
                imageHeight = imageFrame.ImageData.Height_pixels;
                imageWidth = imageFrame.ImageData.Width_pixels;
                intial_img = reshape(uint16(imageData), [imageWidth, imageHeight]);
                
            app.tlCamera.Disarm;
            app.tlCamera.Dispose;
            delete(app.tlCamera);
            figure
            imagesc(intial_img)
            [xl yl]=getpts
            xl=double(xl)
            yl=double(yl)

              x = linspace(-1,1,1152);
               [X,Y] = meshgrid(x,x);
               [theta,r] = cart2pol(X,Y);
               n = [2 2 2 3 3 3 3 4]
                  m = [-2 0 2 -3 -1 1 3 0] 
                idx = r<=1;
                
            app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
            app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
               for ii=2%1:1:length(n)
              z = nan(size(X));
                  
              y = zernfun(n(ii),m(ii),r(idx),theta(idx));


            z = nan(size(X));
                z(idx) = y;
                r1=z;
                
            amp=5
            kt=1
            for k=linspace(-amp,amp,10)
                rt=r1*pi*k+pi;
                rp=mod(rt,2*pi);
                lp=mod(lt,2*pi);
            r2=mod(rp+lp,2*pi);
            %figure
             %   imagesc(mod(rt,2*pi))
                z1=uint8(r2*(255)/(2*pi));
            z2=padarray(z1,[384,0]);
            calllib('Blink_C_wrapper','Write_image', z2,1);
            pause(0.1);
            imageFrame = app.tlCamera.GetPendingFrameOrNull;
                pause(0.1);
                imageData = imageFrame.ImageData.ImageData_monoOrBGR;
                imageHeight = imageFrame.ImageData.Height_pixels;
                imageWidth = imageFrame.ImageData.Width_pixels;
                imageData2D = reshape(uint16(imageData), [imageWidth, imageHeight]);
                
                I(:,:,kt) = imageData2D;
                FM(kt) = wentropy(double(I(yl(1):yl(2),xl(1):xl(2),kt)),'shannon');
               
                imwrite(imageData2D,['zern_img/',num2str(kt),'.tiff']);
                 kt=kt+1;
            end
            [YPRIME PARAMS RESNORM RESIDUAL JACOBIAN] = lorentzfit(linspace(-amp,amp,10),FM/max(FM));
               figure
                plot(linspace(-amp,amp,10),FM/max(FM))
                title('stage1')
                bias(kt) = PARAMS(2);
               end
            
           
            fprintf(app.detLens,['1PA',num2str(app.DetposEditField.Value)]);
            pause(1);
            save('final_zernike.mat','bias')
            % close camera
            app.tlCamera.Disarm;
            app.tlCamera.Dispose;
            delete(app.tlCamera);
        end

        % Value changed function: G_XSlider
        function G_XSliderValueChanged(app, event)
            value = app.G_XSlider.Value;
            app.galvox = value;
            app.G_XEditField.Value = value;
            outputSingleScan(app.s,[0,app.galvox,app.galvoy]);
        end

        % Value changed function: G_YSlider
        function G_YSliderValueChanged(app, event)
            value = app.G_YSlider.Value;
            app.galvoy = value;
            app.G_YEditField.Value = value;
            outputSingleScan(app.s,[0,app.galvox,app.galvoy]);
        end

        % Value changed function: DetmoveDropDown
        function DetmoveDropDownValueChanged(app, event)
            value = app.DetmoveDropDown.Value;
            
        end

        % Callback function
        function ImageButton_2Pushed(app, event)
            app.StopCamButtonPushed(app);
            
            if app.CamLamp.Color(2) == 0
                disp('Camera is disconnected, Need to Connect Camera first.');
                return;
            end
            if app.inUseLamp.Color(2) == 1
                disp('Camera needs to stop streaming first.');
                return;
            end
            if app.rangeEditField.Value < app.stepsizeEditField.Value
                disp('range need to be larger than step size.');
                return;
            end
            foldername = 'save_img';
            if ~exist(foldername)
                mkdir(foldername);
            end
            % start camera
            app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
            app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            load('final_bias_1.mat')
            load('zernikes.mat')
            phase = double(imread('mask_phase_epoch_10_4000.tiff'));
            x=1:1:1152;
            x=x-576;
            lmd=561*10^-9;
            px=8*10^-6;
            f=-100000*10^-2;%11.93
            y=1:1:1152;
            y=y-576;
            [X,Y]=meshgrid(x*px,y*px);

            shift=2*0.00532*(Y)/lmd;

            phase = imresize(phase,[1152,1152]);
            phase = mod(phase,2*pi);
            final_bias(1)=0
  
            rt=(final_bias(1)*pi*mp1+final_bias(2)*pi*mp2+final_bias(3)*pi*mp3+final_bias(4)*pi*mp4+final_bias(5)*pi*mp5+final_bias(6)*pi*mp6+final_bias(7)*pi*mp7+final_bias(8)*pi*mp8+pi);

            r1=mod(1*rt +0*phase-3*shift,2*pi);
            z1=uint8(r1*1*(255)/(2*pi));

            z2=padarray(z1,[384,0]);
            calllib('Blink_C_wrapper','Write_image', z2,1);
            positions = app.DetposEditField.Value - app.rangeEditField.Value:app.stepsizeEditField.Value:app.DetposEditField.Value + app.rangeEditField.Value;
            for i = 1:numel(positions)
                fprintf(app.detLens,['1PA',num2str(positions(i))]);
                pause(1);
                if i == 1
                    pause(1);
                end
                % get the pos
                fprintf(app.detLens,'1TP?');
                pos = fscanf(app.detLens);
                pos = getpos(pos);
                disp(pos);
                % grap one image
                for k=-10:1:10
                    r1=mod(1*rt +k*phase-3*shift,2*pi);
                    z1=uint8(r1*1*(255)/(2*pi));

                    z2=padarray(z1,[384,0]);
                    calllib('Blink_C_wrapper','Write_image', z2,1);
                    pause(0.1);
                    imageFrame = app.tlCamera.GetPendingFrameOrNull;
                    pause(0.1);
                    imageData = imageFrame.ImageData.ImageData_monoOrBGR;
                    imageHeight = imageFrame.ImageData.Height_pixels;
                    imageWidth = imageFrame.ImageData.Width_pixels;
                    imageData2D = reshape(uint16(imageData), [imageWidth, imageHeight]);
                    imwrite(imageData2D,['save_img/test/','phase_',num2str(k),'_location_',num2str(i),'.tiff']);
                
                end
                
            end
            fprintf(app.detLens,['1PA',num2str(app.DetposEditField.Value)]);
            pause(1);
            
            % close camera
            app.tlCamera.Disarm;
            app.tlCamera.Dispose;
            delete(app.tlCamera);


        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)

            
            app.f = figure;
            app.f.Position = [100 100 1024 1024];
            set(app.f,'toolbar','figure');
            hAxes = subplot(1,1,1);
            %hImage = imshow(image,'Parent',hAxes, 'DisplayRange', []); 


            app.mmc.stopSequenceAcquisition;
            %Make sure the buffer is empty
            app.mmc.clearCircularBuffer;
            %Debug
            app.mmc.setCircularBufferMemoryFootprint(1024*10);
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.mmc.setProperty('HamamatsuHam_DCAM','EXPOSURE FULL RANGE','ENABLE');
            app.mmc.setProperty('HamamatsuHam_DCAM','Exposure',app.ExpEditField.Value);
            app.mmc.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            checkBuffer = app.mmc.getRemainingImageCount();
            
            if checkBuffer==0
                pause(0.4);
            end
            
            image = app.mmc.getLastImage();  % returned as a 1D array of signed integers in row-major order
            width = app.mmc.getImageWidth();
            height = app.mmc.getImageHeight();
            pixelType = 'uint16';
            image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
            image = reshape(image, [width, height]); % image should be interpreted as a 2D array
            image = rot90(image);
            hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            %colormapeditor;
            
            app.myhandles = guihandles(app.f);
            app.myhandles.continue = true;
            %app.myhandles.textbox_handle = textbox_handle;
            while (app.myhandles.continue)
                guidata(app.f,app.myhandles);
                if app.imagecontrast_change == true
                    app.imagecontrast_change = false;
                    hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                end
                
                checkBuffer = app.mmc.getRemainingImageCount();
                if checkBuffer==0
                    pause(0.4);
                end
                %startTime = tic;
                image = app.mmc.getLastImage();
                image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                image = rot90(image);
                %image = image(:,end:-1:1);
                hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                set(hImage,'CData',image);
                
                pause(0.15);
                %elTime = toc(startTime);
%                 if (elTime <= (app.ExpEditField.Value/1000))
%                     pause(app.ExpEditField.Value/1000 - elTime);                
%                 else
%                     pause(0.15);
%                 end
                %app.myhandles = guidata(app.f);
            end
            
        end

        % Button pushed function: DisableButton
        function DisableButtonPushed(app, event)
            app.myhandles.continue = false; 
            close(app.f);
            app.mmc.stopSequenceAcquisition;
            app.mmc.clearCircularBuffer;
        end

        % Value changed function: XY_stepDropDown
        function XY_stepDropDownValueChanged(app, event)
            value = app.XY_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.xy_step = 10;
            elseif convertCharsToStrings(value) == '20um'
                app.xy_step = 20;
            elseif convertCharsToStrings(value) == '100um'
                app.xy_step = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.xy_step = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.xy_step = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.xy_step = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.xy_step = 2000;
            end
        end

        % Value changed function: Z_stepDropDown
        function Z_stepDropDownValueChanged(app, event)
            value = app.Z_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.z_step = 10;
            elseif convertCharsToStrings(value) == '20um'
                app.z_step = 20;
            elseif convertCharsToStrings(value) == '100um'
                app.z_step = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.z_step = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.z_step = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.z_step = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.z_step = 2000;
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',0,app.xy_step);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_XEditField.Value = app.mmc.getYPosition();
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',0,-app.xy_step);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_XEditField.Value = app.mmc.getYPosition();
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.mmc.setRelativePosition('ZStage:Z:32',-app.xy_step);
            app.mmc.waitForDevice('ZStage:Z:32');
            app.Stage_ZEditField.Value = app.mmc.getPosition('ZStage:Z:32');
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.mmc.setRelativePosition('ZStage:Z:32',app.xy_step);
            app.mmc.waitForDevice('ZStage:Z:32');
            app.Stage_ZEditField.Value = app.mmc.getPosition('ZStage:Z:32');
        end

        % Button pushed function: InButton
        function InButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',app.z_step,0);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_XEditField.Value = app.mmc.getXPosition();
        end

        % Button pushed function: OutButton
        function OutButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',-app.z_step,0);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_XEditField.Value = app.mmc.getXPosition();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.StartEditField.Value = app.mmc.getXPosition();
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.EndEditField.Value = app.mmc.getXPosition();
        end

        % Button pushed function: saving_DirButton
        function saving_DirButtonPushed(app, event)
            path = uigetdir('D:/','Image saving directory');
            app.dirname = path;
            app.saving_PathEditField.Value = app.dirname;
        end

        % Button pushed function: StackimagingButton
        function StackimagingButtonPushed(app, event)
%             if app.CamLamp_2.Color(2) == 0
%                 disp('Camera is disconnected, Need to Connect Camera first.');
%                 return;
%             end
            if app.StartEditField.Value == 0 || app.EndEditField.Value == 0 || app.StartEditField.Value > app.EndEditField.Value
                disp('start or end position is not saved Or start value need to be smaller than end value');
                return;
            end
            app.mmc.setProperty('HamamatsuHam_DCAM','EXPOSURE FULL RANGE','ENABLE');
            app.mmc.setProperty('HamamatsuHam_DCAM','Exposure',app.ExpEditField.Value);
            [xPos,yPos,zPos]=getXYZposition(app.mmc);
            disp([xPos,yPos,zPos]);
            positions = round(app.StartEditField.Value:app.ZdistanceEditField.Value:app.EndEditField.Value);
            for i=1:numel(positions)
                
                disp(positions(i));
%                 [xPos,yPos,zPos]=getXYZposition(app.mmc);
%                 disp([xPos,yPos,zPos]);
                app.mmc.waitForDevice('HamamatsuHam_DCAM');
                app.mmc.setXYPosition('XYStage:XY:31',positions(i),yPos);
                app.mmc.waitForDevice('XYStage:XY:31');
                
                pause(1);
                app.mmc.snapImage();
                checkBuffer = app.mmc.getRemainingImageCount();      
                if checkBuffer==0
                    pause(0.5);
                end
                
                while app.mmc.isSequenceRunning()
                    pause(0.1);
                end
            
                img = app.mmc.getImage();
                width = app.mmc.getImageWidth();
                height = app.mmc.getImageHeight();
                pixelType = 'uint16';
                image = typecast(img, pixelType);      % pixels must be interpreted as unsigned integers
                image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                image = rot90(image);
                
                [xPos,yPos,zPos]=getXYZposition(app.mmc);
                firstaxis = num2str(round(xPos+30000,0)*10,'%06d');
                img_path= [app.dirname,'/',firstaxis,'.tiff'];
                imwrite(image,img_path);
            
            end
        end

        % Button pushed function: Save_imgButton
        function Save_imgButtonPushed(app, event)
            %app.mmc.stopSequenceAcquisition;
            %app.mmc.clearCircularBuffer;
            %app.mmc.setCircularBufferMemoryFootprint(2048*10);
            % start camera
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.mmc.setProperty('HamamatsuHam_DCAM','EXPOSURE FULL RANGE','ENABLE');
            app.mmc.setProperty('HamamatsuHam_DCAM','Exposure',app.ExpEditField.Value);
            
            disp('snap one image');
            app.mmc.snapImage();
            app.mmc.waitForDevice('HamamatsuHam_DCAM');
            
            checkBuffer = app.mmc.getRemainingImageCount();      
            if checkBuffer==0
                pause(0.5);
            end
            
            while app.mmc.isSequenceRunning()
                pause(0.1);
            end
            
            img = app.mmc.getImage();
            width = app.mmc.getImageWidth();
            height = app.mmc.getImageHeight();
            pixelType = 'uint16';
            image = typecast(img, pixelType);      % pixels must be interpreted as unsigned integers
            image = reshape(image, [width, height]); % image should be interpreted as a 2D array
            image = rot90(image);
                                            
            [xPos,yPos,zPos]=getXYZposition(app.mmc);
            firstaxis = num2str(round(xPos+30000,-1)*10,'%06d');
            img_path= [app.dirname,'/',firstaxis,'.tiff'];
            imwrite(image,img_path);
            
        end

        % Value changed function: Imagecontrast
        function ImagecontrastValueChanged(app, event)
            value = app.Imagecontrast.Value;
            app.imagecontrast_change = true;
            if value < 0 
                app.Imagecontrast.Value = 300;
            elseif value >= 65535
                app.Imagecontrast.Value = 65500;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MotorStagePanel = uipanel(app.UIFigure);
            app.MotorStagePanel.Title = 'Motor Stage';
            app.DetmoveDropDownLabel = uilabel(app.MotorStagePanel);
            app.DetmoveDropDownLabel.Text = 'Det move';
            app.DetmoveDropDown = uidropdown(app.MotorStagePanel);
            app.DetmoveDropDown.Items = {'0.001', '0.005', '0.01', '0.02', '0.05', '0.1', '0.2'};
            app.DetmoveDropDown.ValueChangedFcn = createCallbackFcn(app, @DetmoveDropDownValueChanged, true);
            app.DetmoveDropDown.Value = '0.001';
            app.leftButton = uibutton(app.MotorStagePanel, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = 'left';
            app.rightButton = uibutton(app.MotorStagePanel, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = 'right';
            app.stepsizeEditFieldLabel = uilabel(app.MotorStagePanel);
            app.stepsizeEditFieldLabel.Text = 'stepsize';
            app.stepsizeEditField = uieditfield(app.MotorStagePanel, 'numeric');
            app.stepsizeEditField.Value = 0.1;
            app.ImageButton = uibutton(app.MotorStagePanel, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.rangeEditFieldLabel = uilabel(app.MotorStagePanel);
            app.rangeEditFieldLabel.Text = 'range';
            app.rangeEditField = uieditfield(app.MotorStagePanel, 'numeric');
            app.rangeEditField.Value = 1;
            app.DetposEditFieldLabel = uilabel(app.MotorStagePanel);
            app.DetposEditFieldLabel.Text = 'Det pos';
            app.DetposEditField = uieditfield(app.MotorStagePanel, 'numeric');
            app.zernikeButton = uibutton(app.MotorStagePanel, 'push');
            app.zernikeButton.ButtonPushedFcn = createCallbackFcn(app, @zernikeButtonPushed, true);
            app.zernikeButton.Text = 'zernike';
            app.ThorlabcamPanel = uipanel(app.UIFigure);
            app.ThorlabcamPanel.Title = 'Thorlab cam';
            app.ConnectCamButton = uibutton(app.ThorlabcamPanel, 'push');
            app.ConnectCamButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCamButtonPushed, true);
            app.ConnectCamButton.Text = 'Connect Cam';
            app.DisconnectCamButton = uibutton(app.ThorlabcamPanel, 'push');
            app.DisconnectCamButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectCamButtonPushed, true);
            app.DisconnectCamButton.Text = 'Disconnect Cam';
            app.Exposure_msEditFieldLabel = uilabel(app.ThorlabcamPanel);
            app.Exposure_msEditFieldLabel.Text = 'Exposure_ms';
            app.Exposure_msEditField = uieditfield(app.ThorlabcamPanel, 'numeric');
            app.Exposure_msEditField.Value = 100;
            app.ContrastEditFieldLabel = uilabel(app.ThorlabcamPanel);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.ThorlabcamPanel, 'numeric');
            app.ContrastEditField.Value = 2000;
            app.CamLampLabel = uilabel(app.ThorlabcamPanel);
            app.CamLampLabel.Text = 'Cam';
            app.CamLamp = uilamp(app.ThorlabcamPanel);
            app.StartCamButton = uibutton(app.ThorlabcamPanel, 'push');
            app.StartCamButton.ButtonPushedFcn = createCallbackFcn(app, @StartCamButtonPushed, true);
            app.StartCamButton.Text = 'Start Cam';
            app.inUseLampLabel = uilabel(app.ThorlabcamPanel);
            app.inUseLampLabel.Text = 'inUse';
            app.inUseLamp = uilamp(app.ThorlabcamPanel);
            app.StopCamButton = uibutton(app.ThorlabcamPanel, 'push');
            app.StopCamButton.ButtonPushedFcn = createCallbackFcn(app, @StopCamButtonPushed, true);
            app.StopCamButton.Text = 'Stop Cam';
            app.save_imageButton = uibutton(app.ThorlabcamPanel, 'push');
            app.save_imageButton.ButtonPushedFcn = createCallbackFcn(app, @save_imageButtonPushed, true);
            app.save_imageButton.Text = 'save_image';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.Stage_XEditFieldLabel = uilabel(app.StatusPanel);
            app.Stage_XEditFieldLabel.Text = 'Stage_X';
            app.Stage_XEditField = uieditfield(app.StatusPanel, 'numeric');
            app.Stage_YEditFieldLabel = uilabel(app.StatusPanel);
            app.Stage_YEditFieldLabel.Text = 'Stage_Y';
            app.Stage_YEditField = uieditfield(app.StatusPanel, 'numeric');
            app.Stage_ZEditFieldLabel = uilabel(app.StatusPanel);
            app.Stage_ZEditFieldLabel.Text = 'Stage_Z';
            app.Stage_ZEditField = uieditfield(app.StatusPanel, 'numeric');
            app.LaserPanel = uipanel(app.UIFigure);
            app.LaserPanel.Title = 'Laser';
            app.Switch = uiswitch(app.LaserPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.PowerEditFieldLabel = uilabel(app.LaserPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.LaserPanel, 'numeric');
            app.PowerEditField.ValueChangedFcn = createCallbackFcn(app, @PowerEditFieldValueChanged, true);
            app.PowerEditField.Value = 1;
            app.galvocontrolPanel = uipanel(app.UIFigure);
            app.galvocontrolPanel.Title = 'galvo control';
            app.G_XSliderLabel = uilabel(app.galvocontrolPanel);
            app.G_XSliderLabel.Text = 'G_X';
            app.G_XSlider = uislider(app.galvocontrolPanel);
            app.G_XSlider.Limits = [-0.2 0.2];
            app.G_XSlider.MajorTicks = [-0.3 -0.2 -0.1 0 0.1 0.2 0.3];
            app.G_XSlider.MajorTickLabels = {'-0.3', '-0.2', '0.1', '0.0', '0.1', '0.2', '0.3'};
            app.G_XSlider.ValueChangedFcn = createCallbackFcn(app, @G_XSliderValueChanged, true);
            app.G_YSliderLabel = uilabel(app.galvocontrolPanel);
            app.G_YSliderLabel.Text = 'G_Y';
            app.G_YSlider = uislider(app.galvocontrolPanel);
            app.G_YSlider.Limits = [-0.2 0.2];
            app.G_YSlider.MajorTicks = [-0.2 -0.1 0 0.1 0.2];
            app.G_YSlider.ValueChangedFcn = createCallbackFcn(app, @G_YSliderValueChanged, true);
            app.G_XEditFieldLabel = uilabel(app.galvocontrolPanel);
            app.G_XEditFieldLabel.Text = 'G_X';
            app.G_XEditField = uieditfield(app.galvocontrolPanel, 'numeric');
            app.G_YEditFieldLabel = uilabel(app.galvocontrolPanel);
            app.G_YEditFieldLabel.Text = 'G_Y';
            app.G_YEditField = uieditfield(app.galvocontrolPanel, 'numeric');
            app.HamamatsucamPanel = uipanel(app.UIFigure);
            app.HamamatsucamPanel.Title = 'Hamamatsu cam';
            app.EnableButton = uibutton(app.HamamatsucamPanel, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.DisableButton = uibutton(app.HamamatsucamPanel, 'push');
            app.DisableButton.ButtonPushedFcn = createCallbackFcn(app, @DisableButtonPushed, true);
            app.DisableButton.Text = 'Disable';
            app.ExpEditFieldLabel = uilabel(app.HamamatsucamPanel);
            app.ExpEditFieldLabel.Text = 'Exp';
            app.ExpEditField = uieditfield(app.HamamatsucamPanel, 'numeric');
            app.ExpEditField.Value = 100;
            app.saving_PathEditFieldLabel = uilabel(app.HamamatsucamPanel);
            app.saving_PathEditFieldLabel.Text = 'saving_Path';
            app.saving_PathEditField = uieditfield(app.HamamatsucamPanel, 'text');
            app.saving_DirButton = uibutton(app.HamamatsucamPanel, 'push');
            app.saving_DirButton.ButtonPushedFcn = createCallbackFcn(app, @saving_DirButtonPushed, true);
            app.saving_DirButton.Text = 'saving_Dir';
            app.Save_imgButton = uibutton(app.HamamatsucamPanel, 'push');
            app.Save_imgButton.ButtonPushedFcn = createCallbackFcn(app, @Save_imgButtonPushed, true);
            app.Save_imgButton.Text = 'Save_img';
            app.ImagecontrastLabel = uilabel(app.HamamatsucamPanel);
            app.ImagecontrastLabel.Text = 'Image contrast';
            app.Imagecontrast = uieditfield(app.HamamatsucamPanel, 'numeric');
            app.Imagecontrast.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastValueChanged, true);
            app.Imagecontrast.Value = 4000;
            app.SampleStageStackimagingPanel = uipanel(app.UIFigure);
            app.SampleStageStackimagingPanel.Title = 'Sample Stage/Stack imaging';
            app.UpButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.InButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.InButton.ButtonPushedFcn = createCallbackFcn(app, @InButtonPushed, true);
            app.InButton.Text = 'In';
            app.OutButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.OutButton.ButtonPushedFcn = createCallbackFcn(app, @OutButtonPushed, true);
            app.OutButton.Text = 'Out';
            app.XY_stepDropDownLabel = uilabel(app.SampleStageStackimagingPanel);
            app.XY_stepDropDownLabel.Text = 'XY_step';
            app.XY_stepDropDown = uidropdown(app.SampleStageStackimagingPanel);
            app.XY_stepDropDown.Items = {'10um', '20um', '100um', '200um', '500um', '1mm', '2mm'};
            app.XY_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @XY_stepDropDownValueChanged, true);
            app.XY_stepDropDown.Value = '10um';
            app.Z_stepDropDownLabel = uilabel(app.SampleStageStackimagingPanel);
            app.Z_stepDropDownLabel.Text = 'Z_step';
            app.Z_stepDropDown = uidropdown(app.SampleStageStackimagingPanel);
            app.Z_stepDropDown.Items = {'10um', '20um', '100um', '200um', '500um', '1mm', '2mm'};
            app.Z_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Z_stepDropDownValueChanged, true);
            app.Z_stepDropDown.Value = '10um';
            app.StartEditFieldLabel = uilabel(app.SampleStageStackimagingPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.SampleStageStackimagingPanel, 'numeric');
            app.EndEditFieldLabel = uilabel(app.SampleStageStackimagingPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.SampleStageStackimagingPanel, 'numeric');
            app.SaveButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SaveButton_2 = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.StackimagingButton = uibutton(app.SampleStageStackimagingPanel, 'push');
            app.StackimagingButton.ButtonPushedFcn = createCallbackFcn(app, @StackimagingButtonPushed, true);
            app.StackimagingButton.Text = 'Stack imaging';
            app.ZdistanceEditFieldLabel = uilabel(app.SampleStageStackimagingPanel);
            app.ZdistanceEditFieldLabel.Text = 'Z distance';
            app.ZdistanceEditField = uieditfield(app.SampleStageStackimagingPanel, 'numeric');
            app.ZdistanceEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lightsheet_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end