classdef Lightsheet_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MotorStagePanel            matlab.ui.container.Panel
        DetmoveDropDownLabel       matlab.ui.control.Label
        DetmoveDropDown            matlab.ui.control.DropDown
        leftButton                 matlab.ui.control.Button
        rightButton                matlab.ui.control.Button
        DetposEditFieldLabel       matlab.ui.control.Label
        DetposEditField            matlab.ui.control.NumericEditField
        StatusPanel                matlab.ui.container.Panel
        GalvoXEditFieldLabel       matlab.ui.control.Label
        GalvoXEditField            matlab.ui.control.NumericEditField
        GalvoYEditFieldLabel       matlab.ui.control.Label
        GalvoYEditField            matlab.ui.control.NumericEditField
        Stage_XEditFieldLabel      matlab.ui.control.Label
        Stage_XEditField           matlab.ui.control.NumericEditField
        Stage_YEditFieldLabel      matlab.ui.control.Label
        Stage_YEditField           matlab.ui.control.NumericEditField
        Stage_ZEditFieldLabel      matlab.ui.control.Label
        Stage_ZEditField           matlab.ui.control.NumericEditField
        LaserPanel                 matlab.ui.container.Panel
        Switch                     matlab.ui.control.Switch
        PowerEditFieldLabel        matlab.ui.control.Label
        PowerEditField             matlab.ui.control.NumericEditField
        ShutterButton              matlab.ui.control.StateButton
        AFGPanel                   matlab.ui.container.Panel
        TopEditFieldLabel          matlab.ui.control.Label
        TopEditField               matlab.ui.control.NumericEditField
        BotEditFieldLabel          matlab.ui.control.Label
        BotEditField               matlab.ui.control.NumericEditField
        roll_scanButton            matlab.ui.control.Button
        AngEditFieldLabel          matlab.ui.control.Label
        AngEditField               matlab.ui.control.NumericEditField
        scanButton                 matlab.ui.control.StateButton
        GalvoXSliderLabel          matlab.ui.control.Label
        GalvoXSlider               matlab.ui.control.Slider
        GalvoYSliderLabel          matlab.ui.control.Label
        GalvoYSlider               matlab.ui.control.Slider
        galvo_resetButton          matlab.ui.control.Button
        SamplestagePanel           matlab.ui.container.Panel
        UpButton                   matlab.ui.control.Button
        DownButton                 matlab.ui.control.Button
        LeftButton                 matlab.ui.control.Button
        RightButton                matlab.ui.control.Button
        InButton                   matlab.ui.control.Button
        OutButton                  matlab.ui.control.Button
        XY_stepDropDownLabel       matlab.ui.control.Label
        XY_stepDropDown            matlab.ui.control.DropDown
        Z_stepDropDownLabel        matlab.ui.control.Label
        Z_stepDropDown             matlab.ui.control.DropDown
        CameraPanel                matlab.ui.container.Panel
        EnableButton               matlab.ui.control.Button
        ExposureEditFieldLabel     matlab.ui.control.Label
        ExposureEditField          matlab.ui.control.NumericEditField
        ContrastEditFieldLabel     matlab.ui.control.Label
        ContrastEditField          matlab.ui.control.NumericEditField
        saving_DirButton           matlab.ui.control.Button
        saving_PathEditFieldLabel  matlab.ui.control.Label
        saving_PathEditField       matlab.ui.control.EditField
        save_ImgButton             matlab.ui.control.Button
        DisableButton              matlab.ui.control.Button
    end

     properties (Access = public)
        parameters; % Description
        
        serialNumbers;
        f; % image window
        
        keepRunning;
        
        detLens;
        s561nm;
        afg;
        s;
        shutterState;
        galvox;
        galvoy;
        mmc;
        xy_step;
        z_step;
        myhandles;
        dirname;
        saveImage;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('D:/lightsheet_test/lib_device'));
            addpath(genpath('D:/lightsheet_test/AfgCode'));
            addpath(genpath('D:/lightsheet_test/UsefulFun'));
            addpath(genpath('C:\Program Files\Meadowlark Optics\Blink 1920 HDMI\SDK'));
            addpath(genpath('C:\Users\Greenabaumlab\Downloads\zernike'));
            %app.parameters = evalin('base','controlParameters');
            % initialize moter
            portDetMotor = 'COM4';
            [app.detLens] = initMotor(portDetMotor);
            fprintf(app.detLens,['1PA','6']);
            % initialize laser
            portLaser = 'COM3';
            [app.s561nm] = initObis(portLaser);
            
            pause(5);
            disp('Initialize the laser');
            disp('Initialize the motor');
            fprintf(app.detLens,'1TP?');
            pos = fscanf(app.detLens);
            app.DetposEditField.Value = getpos(pos);
%             loadlibrary('Blink_C_wrapper.dll', 'Blink_C_wrapper.h');
%             pause(5)
% 
%             lut_file = 'C:\Program Files\Meadowlark Optics\Blink 1920 HDMI\LUT Files\slm5504_at532.LUT';
% 
%            pause(5)
%             calllib('Blink_C_wrapper', 'Create_SDK');
% 
%              pause(5)
%          disp('Blink SDK was successfully constructed');
% 
% % Load the lookup table to the controller. Make sure that your com port is
% % correct. You can open Blink to validate the COM port. 
%           calllib('Blink_C_wrapper', 'Load_lut', lut_file);

% pause(5)
            %app.tlCameraSDK.Dispose;
            % connect with afg
            app.afg = EstablishComAfg();
            app.s = daq.createSession('mcc');
            shutter = addAnalogOutputChannel(app.s,'Board0', 'Ao0', 'Voltage');
            galvox = addAnalogOutputChannel(app.s,'Board0', 'Ao1', 'Voltage');
            galvoy = addAnalogOutputChannel(app.s,'Board0', 'Ao2', 'Voltage');
            app.shutterState = 0;
            app.galvox = 0;
            app.galvoy = 0;
            pathToCfgFile = 'C:\Micro-Manager-1.4\MMConfig_10_04_2022.cfg';
            app.mmc = InitiateHardware(pathToCfgFile);
            app.Stage_XEditField.Value = app.mmc.getXPosition();
            app.Stage_YEditField.Value = app.mmc.getYPosition();
            app.Stage_ZEditField.Value = app.mmc.getPosition('ZStage:Z:32');
            app.xy_step = 10;
            app.z_step = 10;
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            stepSize = app.DetmoveDropDown.Value;
            fprintf(app.detLens,['1PR',num2str(stepSize)]);
            pause(0.5);
            fprintf(app.detLens,'1TP?');
            pos = fscanf(app.detLens);
            app.DetposEditField.Value = getpos(pos);
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            stepSize = app.DetmoveDropDown.Value;
            fprintf(app.detLens,['1PR-',num2str(stepSize)]);
            pause(0.5);
            fprintf(app.detLens,'1TP?');
            pos = fscanf(app.detLens);
            app.DetposEditField.Value = getpos(pos);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.StopCamButtonPushed(app);
            %app.DisconnectCamButtonPushed(app);
            
            delete(app)
        end

        % Value changed function: PowerEditField
        function PowerEditFieldValueChanged(app, event)
            value = app.PowerEditField.Value;
            if value >= 20
                value = 20;
            elseif value <= 0
                value = 1;
            end
            ChangePowerObis(app.s561nm,value,20);
        end

        % Callback function
        function zernikeButtonPushed(app, event)
            app.StopCamButtonPushed(app);
            
            if app.CamLamp.Color(2) == 0
                disp('Camera is disconnected, Need to Connect Camera first.');
                return;
            end
            if app.inUseLamp.Color(2) == 1
                disp('Camera needs to stop streaming first.');
                return;
            end
            if app.rangeEditField.Value < app.stepsizeEditField.Value
                disp('range need to be larger than step size.');
                return;
            end
            foldername = 'zern_img';
            if ~exist(foldername)
                mkdir(foldername);
            end
            % start camera
            app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
            app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
            
            x=1:1:1152;
            x=x-576;
            lmd=561*10^-9;
             px=8*10^-6;
             f=12*10^-2;%19.99*10^-2;
             y=x;
             [X,Y]=meshgrid(x*px,y*px);

            shift=0.0*(X)/lmd;
            R=(X.^2+Y.^2);
             p=100;
             rqpf=(sqrt(R/(px.^2))/p).^4;
            lt=-2*pi*R/(lmd*f);
            lens=mod((lt),2*pi);
            lens1=uint8(lens*(255)/(2*pi));
             lens2=padarray(lens1,[384,0]);
            calllib('Blink_C_wrapper','Write_image', lens2,1);
            pause(0.1)
            imageFrame = app.tlCamera.GetPendingFrameOrNull;
                pause(0.1);
            imageData = imageFrame.ImageData.ImageData_monoOrBGR;
                imageHeight = imageFrame.ImageData.Height_pixels;
                imageWidth = imageFrame.ImageData.Width_pixels;
                intial_img = reshape(uint16(imageData), [imageWidth, imageHeight]);
                
            app.tlCamera.Disarm;
            app.tlCamera.Dispose;
            delete(app.tlCamera);
            figure
            imagesc(intial_img)
            [xl yl]=getpts
            xl=double(xl)
            yl=double(yl)

              x = linspace(-1,1,1152);
               [X,Y] = meshgrid(x,x);
               [theta,r] = cart2pol(X,Y);
               n = [2 2 2 3 3 3 3 4]
                  m = [-2 0 2 -3 -1 1 3 0] 
                idx = r<=1;
                
            app.tlCamera = app.tlCameraSDK.OpenCamera(app.serialNumbers.Item(0), false);
            app.tlCamera.ExposureTime_us = app.Exposure_msEditField.Value * 1000;
            app.tlCamera.OperationMode = Thorlabs.TSI.TLCameraInterfaces.OperationMode.SoftwareTriggered;
            app.tlCamera.FramesPerTrigger_zeroForUnlimited = 0;
            app.tlCamera.Arm;
            app.tlCamera.IssueSoftwareTrigger;
               for ii=2%1:1:length(n)
              z = nan(size(X));
                  
              y = zernfun(n(ii),m(ii),r(idx),theta(idx));


            z = nan(size(X));
                z(idx) = y;
                r1=z;
                
            amp=5
            kt=1
            for k=linspace(-amp,amp,10)
                rt=r1*pi*k+pi;
                rp=mod(rt,2*pi);
                lp=mod(lt,2*pi);
            r2=mod(rp+lp,2*pi);
            %figure
             %   imagesc(mod(rt,2*pi))
                z1=uint8(r2*(255)/(2*pi));
            z2=padarray(z1,[384,0]);
            calllib('Blink_C_wrapper','Write_image', z2,1);
            pause(0.1);
            imageFrame = app.tlCamera.GetPendingFrameOrNull;
                pause(0.1);
                imageData = imageFrame.ImageData.ImageData_monoOrBGR;
                imageHeight = imageFrame.ImageData.Height_pixels;
                imageWidth = imageFrame.ImageData.Width_pixels;
                imageData2D = reshape(uint16(imageData), [imageWidth, imageHeight]);
                
                I(:,:,kt) = imageData2D;
                FM(kt) = wentropy(double(I(yl(1):yl(2),xl(1):xl(2),kt)),'shannon');
               
                imwrite(imageData2D,['zern_img/',num2str(kt),'.tiff']);
                 kt=kt+1;
            end
            [YPRIME PARAMS RESNORM RESIDUAL JACOBIAN] = lorentzfit(linspace(-amp,amp,10),FM/max(FM));
               figure
                plot(linspace(-amp,amp,10),FM/max(FM))
                title('stage1')
                bias(kt) = PARAMS(2);
               end
            
           
            fprintf(app.detLens,['1PA',num2str(app.DetposEditField.Value)]);
            pause(1);
            save('final_zernike.mat','bias')
            % close camera
            app.tlCamera.Disarm;
            app.tlCamera.Dispose;
            delete(app.tlCamera);
        end

        % Value changed function: scanButton
        function scanButtonValueChanged(app, event)
            value = app.scanButton.Value;
            
            if app.TopEditField.Value >=2000
                disp('top vlotage is too high');
                return
            elseif app.BotEditField.Value <= -2000
                disp('bot vlotage is too high');
                return
            elseif app.TopEditField.Value < app.BotEditField.Value
                disp('top vlotage should be higher than bot voltage');
                return
            end
            
            if value == 1
                % start scan
                SetAfgRamp(app.afg,app.BotEditField.Value,app.TopEditField.Value,500,50,1);
                fwrite(app.afg, ':output1 on;');
                fwrite(app.afg, ':source2:function DC');
                fwrite(app.afg, [':source2:voltage:offset ',num2str(0),'mv']);
                fwrite(app.afg, ':output2 on;');
            else
                fwrite(app.afg, ':source1:function DC');
                fwrite(app.afg, [':source1:voltage:offset ',num2str((app.BotEditField.Value+app.TopEditField.Value)/2),'mv']);
                fwrite(app.afg, ':output1 on;');
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if convertCharsToStrings(value) == 'On'
                disp('On');
                SwitchOnOffObis(app.s561nm,1);
            elseif convertCharsToStrings(value) == 'Off'
                disp('Off');
                SwitchOnOffObis(app.s561nm,0);
            end
        end

        % Button pushed function: roll_scanButton
        function roll_scanButtonPushed(app, event)
            a = 1;
        end

        % Value changed function: GalvoXSlider
        function GalvoXSliderValueChanged(app, event)
            value = app.GalvoXSlider.Value;
            app.galvox = value;
            outputSingleScan(app.s,[app.shutterState,app.galvox,app.galvoy]);
        end

        % Value changed function: GalvoYSlider
        function GalvoYSliderValueChanged(app, event)
            value = app.GalvoYSlider.Value;
            app.galvoy = value;
            outputSingleScan(app.s,[app.shutterState,app.galvox,app.galvoy]);
        end

        % Button pushed function: galvo_resetButton
        function galvo_resetButtonPushed(app, event)
            app.galvox = 0;
            app.galvoy = 0;
            app.GalvoXSlider.Value = app.galvox;
            app.GalvoYSlider.Value = app.galvoy;
            outputSingleScan(app.s,[app.shutterState,app.galvox,app.galvoy]);
        end

        % Value changed function: ShutterButton
        function ShutterButtonValueChanged(app, event)
            value = app.ShutterButton.Value;
            if value == 1
                app.shutterState = 3;
                outputSingleScan(app.s,[app.shutterState,app.galvox,app.galvoy]);
            else
                app.shutterState = 0;
                outputSingleScan(app.s,[app.shutterState,app.galvox,app.galvoy]);
            end
        end

        % Value changed function: XY_stepDropDown
        function XY_stepDropDownValueChanged(app, event)
            value = app.XY_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.xy_step = 10;
            elseif convertCharsToStrings(value) == '20um'
                app.xy_step = 20;
            elseif convertCharsToStrings(value) == '100um'
                app.xy_step = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.xy_step = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.xy_step = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.xy_step = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.xy_step = 2000;
            end
        end

        % Value changed function: Z_stepDropDown
        function Z_stepDropDownValueChanged(app, event)
            value = app.Z_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.z_step = 10;
            elseif convertCharsToStrings(value) == '20um'
                app.z_step = 20;
            elseif convertCharsToStrings(value) == '100um'
                app.z_step = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.z_step = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.z_step = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.z_step = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.z_step = 2000;
            end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',0,-app.xy_step);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_YEditField.Value = app.mmc.getYPosition();
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',0,app.xy_step);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_YEditField.Value = app.mmc.getYPosition();
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',app.xy_step,0);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_XEditField.Value = app.mmc.getXPosition();
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            app.mmc.setRelativeXYPosition('XYStage:XY:31',-app.xy_step,0);
            app.mmc.waitForDevice('XYStage:XY:31');
            app.Stage_XEditField.Value = app.mmc.getXPosition();
%             disp(app.mmc.getRemainingImageCount())
%             app.mmc.setProperty('HamamatsuHam_DCAM','Exposure',20);
        end

        % Button pushed function: InButton
        function InButtonPushed(app, event)
            app.mmc.setRelativePosition('ZStage:Z:32',app.z_step);
            app.xy_step = 10;
            app.mmc.waitForDevice('ZStage:Z:32');
            app.Stage_ZEditField.Value = app.mmc.getPosition('ZStage:Z:32');
        end

        % Button pushed function: OutButton
        function OutButtonPushed(app, event)
            app.mmc.setRelativePosition('ZStage:Z:32',-app.z_step);
            app.mmc.waitForDevice('ZStage:Z:32');
            app.Stage_ZEditField.Value = app.mmc.getPosition('ZStage:Z:32');
        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)
            % open shutter
            app.shutterState = 4;
            outputSingleScan(app.s,[app.shutterState,app.galvox,app.galvoy]);
            
            app.f = figure;
            app.f.Position = [100 100 1024 1024];
            set(app.f,'toolbar','figure');
            hAxes = subplot(1,1,1);
            %Make sure the buffer is empty
            app.mmc.stopSequenceAcquisition;
            app.mmc.clearCircularBuffer;
            app.mmc.setCircularBufferMemoryFootprint(1024*10);
            % start camera
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.mmc.setProperty('HamamatsuHam_DCAM','Exposure',app.ExposureEditField.Value);
            app.mmc.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            checkBuffer = app.mmc.getRemainingImageCount();
            if checkBuffer==0
                pause(0.5);  
            end
            % grap one image
            image = app.mmc.getLastImage();  % returned as a 1D array of signed integers in row-major order
            width = app.mmc.getImageWidth();
            height = app.mmc.getImageHeight();
            pixelType = 'uint16';
            image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
            image = reshape(image, [width, height]); % image should be interpreted as a 2D array
            image = rot90(image);
            hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.ContrastEditField.Value]);
            app.myhandles = guihandles(app.f);
            app.myhandles.continue = true;
            while (app.myhandles.continue)
                guidata(app.f,app.myhandles);
%                 if app.imagecontrast_change == true
%                     app.imagecontrast_change = false;
%                     hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.ContrastEditField.Value]);
%                 end
                
                checkBuffer = app.mmc.getRemainingImageCount();
                if checkBuffer==0
                    pause(0.5);
                end
                startTime = tic;                
                image = app.mmc.getLastImage();
                image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                image = rot90(image);
                %image = image(:,end:-1:1);
                hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.ContrastEditField.Value]);
                set(hImage,'CData',image);
                if (app.saveImage)
                    app.saveImage = false;
                    nameOfFile = [app.dirname,'/GalvoX_',num2str(app.GalvoXEditField.Value),'_GalvoY_',num2str(app.GalvoYEditField.Value),'_',datestr(now,'YY_mmmm_dd_HH_MM_SS'),'.tiff'];
                    disp(nameOfFile)
                    imwrite(image, nameOfFile);    
                    guidata(app.f,app.myhandles);
                end
                elTime = toc(startTime);
                if (elTime <= app.ExposureEditField.Value/1000)
                    pause(app.ExposureEditField.Value/1000 - elTime);                
                else
                    pause(0.15);
                end
                
            end
        end

        % Button pushed function: saving_DirButton
        function saving_DirButtonPushed(app, event)
            path = uigetdir('D:/','Image saving directory');
            app.dirname = path;
            app.saving_PathEditField.Value = app.dirname;
        end

        % Button pushed function: save_ImgButton
        function save_ImgButtonPushed(app, event)
            if app.saving_PathEditField.Value == ""
                    disp('Please set the saving directory.')
                    return;
            end
            app.saveImage = true;
        end

        % Button pushed function: DisableButton
        function DisableButtonPushed(app, event)
            app.myhandles.continue = false; 
            close(app.f);
            app.mmc.stopSequenceAcquisition;
            app.mmc.clearCircularBuffer;
            % close shutter
            app.shutterState = 0;
            outputSingleScan(app.s,[app.shutterState,app.galvox,app.galvoy]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MotorStagePanel = uipanel(app.UIFigure);
            app.MotorStagePanel.Title = 'Motor Stage';
            app.DetmoveDropDownLabel = uilabel(app.MotorStagePanel);
            app.DetmoveDropDownLabel.Text = 'Det move';
            app.DetmoveDropDown = uidropdown(app.MotorStagePanel);
            app.DetmoveDropDown.Items = {'0.01', '0.02', '0.05', '0.1', '0.2'};
            app.DetmoveDropDown.Value = '0.01';
            app.leftButton = uibutton(app.MotorStagePanel, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = 'left';
            app.rightButton = uibutton(app.MotorStagePanel, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = 'right';
            app.DetposEditFieldLabel = uilabel(app.MotorStagePanel);
            app.DetposEditFieldLabel.Text = 'Det pos';
            app.DetposEditField = uieditfield(app.MotorStagePanel, 'numeric');
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.GalvoXEditFieldLabel = uilabel(app.StatusPanel);
            app.GalvoXEditFieldLabel.Text = 'GalvoX';
            app.GalvoXEditField = uieditfield(app.StatusPanel, 'numeric');
            app.GalvoYEditFieldLabel = uilabel(app.StatusPanel);
            app.GalvoYEditFieldLabel.Text = 'GalvoY';
            app.GalvoYEditField = uieditfield(app.StatusPanel, 'numeric');
            app.Stage_XEditFieldLabel = uilabel(app.StatusPanel);
            app.Stage_XEditFieldLabel.Text = 'Stage_X';
            app.Stage_XEditField = uieditfield(app.StatusPanel, 'numeric');
            app.Stage_YEditFieldLabel = uilabel(app.StatusPanel);
            app.Stage_YEditFieldLabel.Text = 'Stage_Y';
            app.Stage_YEditField = uieditfield(app.StatusPanel, 'numeric');
            app.Stage_ZEditFieldLabel = uilabel(app.StatusPanel);
            app.Stage_ZEditFieldLabel.Text = 'Stage_Z';
            app.Stage_ZEditField = uieditfield(app.StatusPanel, 'numeric');
            app.LaserPanel = uipanel(app.UIFigure);
            app.LaserPanel.Title = 'Laser';
            app.Switch = uiswitch(app.LaserPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.PowerEditFieldLabel = uilabel(app.LaserPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.LaserPanel, 'numeric');
            app.PowerEditField.ValueChangedFcn = createCallbackFcn(app, @PowerEditFieldValueChanged, true);
            app.PowerEditField.Value = 1;
            app.ShutterButton = uibutton(app.LaserPanel, 'state');
            app.ShutterButton.ValueChangedFcn = createCallbackFcn(app, @ShutterButtonValueChanged, true);
            app.ShutterButton.Text = 'Shutter';
            app.AFGPanel = uipanel(app.UIFigure);
            app.AFGPanel.Title = 'AFG';
            app.TopEditFieldLabel = uilabel(app.AFGPanel);
            app.TopEditFieldLabel.Text = 'Top';
            app.TopEditField = uieditfield(app.AFGPanel, 'numeric');
            app.TopEditField.Value = 500;
            app.BotEditFieldLabel = uilabel(app.AFGPanel);
            app.BotEditFieldLabel.Text = 'Bot';
            app.BotEditField = uieditfield(app.AFGPanel, 'numeric');
            app.BotEditField.Value = -500;
            app.roll_scanButton = uibutton(app.AFGPanel, 'push');
            app.roll_scanButton.ButtonPushedFcn = createCallbackFcn(app, @roll_scanButtonPushed, true);
            app.roll_scanButton.Text = 'roll_scan';
            app.AngEditFieldLabel = uilabel(app.AFGPanel);
            app.AngEditFieldLabel.Text = 'Ang';
            app.AngEditField = uieditfield(app.AFGPanel, 'numeric');
            app.scanButton = uibutton(app.AFGPanel, 'state');
            app.scanButton.ValueChangedFcn = createCallbackFcn(app, @scanButtonValueChanged, true);
            app.scanButton.Text = 'scan';
            app.GalvoXSliderLabel = uilabel(app.AFGPanel);
            app.GalvoXSliderLabel.Text = 'GalvoX';
            app.GalvoXSlider = uislider(app.AFGPanel);
            app.GalvoXSlider.Limits = [-0.4 0.4];
            app.GalvoXSlider.MajorTicks = [-0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4];
            app.GalvoXSlider.ValueChangedFcn = createCallbackFcn(app, @GalvoXSliderValueChanged, true);
            app.GalvoYSliderLabel = uilabel(app.AFGPanel);
            app.GalvoYSliderLabel.Text = 'GalvoY';
            app.GalvoYSlider = uislider(app.AFGPanel);
            app.GalvoYSlider.Limits = [-0.4 0.4];
            app.GalvoYSlider.ValueChangedFcn = createCallbackFcn(app, @GalvoYSliderValueChanged, true);
            app.galvo_resetButton = uibutton(app.AFGPanel, 'push');
            app.galvo_resetButton.ButtonPushedFcn = createCallbackFcn(app, @galvo_resetButtonPushed, true);
            app.galvo_resetButton.Text = 'galvo_reset';
            app.SamplestagePanel = uipanel(app.UIFigure);
            app.SamplestagePanel.Title = 'Sample stage';
            app.UpButton = uibutton(app.SamplestagePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.SamplestagePanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.SamplestagePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.SamplestagePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.InButton = uibutton(app.SamplestagePanel, 'push');
            app.InButton.ButtonPushedFcn = createCallbackFcn(app, @InButtonPushed, true);
            app.InButton.Text = 'In';
            app.OutButton = uibutton(app.SamplestagePanel, 'push');
            app.OutButton.ButtonPushedFcn = createCallbackFcn(app, @OutButtonPushed, true);
            app.OutButton.Text = 'Out';
            app.XY_stepDropDownLabel = uilabel(app.SamplestagePanel);
            app.XY_stepDropDownLabel.Text = 'XY_step';
            app.XY_stepDropDown = uidropdown(app.SamplestagePanel);
            app.XY_stepDropDown.Items = {'10um', '20um', '100um', '200um', '500um', '1mm', '2mm'};
            app.XY_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @XY_stepDropDownValueChanged, true);
            app.XY_stepDropDown.Value = '10um';
            app.Z_stepDropDownLabel = uilabel(app.SamplestagePanel);
            app.Z_stepDropDownLabel.Text = 'Z_step';
            app.Z_stepDropDown = uidropdown(app.SamplestagePanel);
            app.Z_stepDropDown.Items = {'10um', '20um', '100um', '200um', '500um', '1mm', '2mm'};
            app.Z_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Z_stepDropDownValueChanged, true);
            app.Z_stepDropDown.Value = '10um';
            app.CameraPanel = uipanel(app.UIFigure);
            app.CameraPanel.Title = 'Camera';
            app.EnableButton = uibutton(app.CameraPanel, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.ExposureEditFieldLabel = uilabel(app.CameraPanel);
            app.ExposureEditFieldLabel.Text = 'Exposure';
            app.ExposureEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ExposureEditField.ValueDisplayFormat = '%.0f';
            app.ExposureEditField.Value = 10;
            app.ContrastEditFieldLabel = uilabel(app.CameraPanel);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.CameraPanel, 'numeric');
            app.ContrastEditField.Limits = [10 60000];
            app.ContrastEditField.ValueDisplayFormat = '%.0f';
            app.ContrastEditField.Value = 5000;
            app.saving_DirButton = uibutton(app.CameraPanel, 'push');
            app.saving_DirButton.ButtonPushedFcn = createCallbackFcn(app, @saving_DirButtonPushed, true);
            app.saving_DirButton.Text = 'saving_Dir';
            app.saving_PathEditFieldLabel = uilabel(app.CameraPanel);
            app.saving_PathEditFieldLabel.Text = 'saving_Path';
            app.saving_PathEditField = uieditfield(app.CameraPanel, 'text');
            app.save_ImgButton = uibutton(app.CameraPanel, 'push');
            app.save_ImgButton.ButtonPushedFcn = createCallbackFcn(app, @save_ImgButtonPushed, true);
            app.save_ImgButton.Text = 'save_Img';
            app.DisableButton = uibutton(app.CameraPanel, 'push');
            app.DisableButton.ButtonPushedFcn = createCallbackFcn(app, @DisableButtonPushed, true);
            app.DisableButton.Text = 'Disable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lightsheet_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end