classdef app_for_lightsheet_V2_main_autofocus_auto_start_end < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ShutterButton                  matlab.ui.control.StateButton
        LightwavelightDropDownLabel    matlab.ui.control.Label
        Lightwavelight                 matlab.ui.control.DropDown
        LaserPowermWEditFieldLabel     matlab.ui.control.Label
        LaserPower                     matlab.ui.control.NumericEditField
        galvo_XSliderLabel             matlab.ui.control.Label
        galvo_XSlider                  matlab.ui.control.Slider
        galvo_YSliderLabel             matlab.ui.control.Label
        galvo_YSlider                  matlab.ui.control.Slider
        Reset_galvo_XYButton           matlab.ui.control.Button
        EnableCam                      matlab.ui.control.Button
        DisableCam                     matlab.ui.control.Button
        ImagecontrastLabel             matlab.ui.control.Label
        Imagecontrast                  matlab.ui.control.NumericEditField
        FilterwheelDropDownLabel       matlab.ui.control.Label
        Filterwheel                    matlab.ui.control.DropDown
        Button                         matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        SaveImage                      matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Updown_stepDropDownLabel       matlab.ui.control.Label
        Updown_stepDropDown            matlab.ui.control.DropDown
        Inout_stepDropDownLabel        matlab.ui.control.Label
        Inout_stepDropDown             matlab.ui.control.DropDown
        Leftright_stepDropDownLabel    matlab.ui.control.Label
        Leftright_stepDropDown         matlab.ui.control.DropDown
        Rotation_stepDropDownLabel     matlab.ui.control.Label
        Rotation_stepDropDown          matlab.ui.control.DropDown
        LIghtpathDropDownLabel         matlab.ui.control.Label
        LIghtpathDropDown              matlab.ui.control.DropDown
        UITable_stage                  matlab.ui.control.Table
        UITable_path                   matlab.ui.control.Table
        UITable_motor                  matlab.ui.control.Table
        UITable_afg                    matlab.ui.control.Table
        AFG_scanButton                 matlab.ui.control.StateButton
        UITable_offset                 matlab.ui.control.Table
        SaveImagetwopathButton         matlab.ui.control.Button
        ImagesavingdirectoryButton     matlab.ui.control.Button
        savepathEditFieldLabel         matlab.ui.control.Label
        savepathEditField              matlab.ui.control.EditField
        OffsetfordifferentchannelsPanel  matlab.ui.container.Panel
        Ch_488_galvoxEditFieldLabel    matlab.ui.control.Label
        Ch_488_galvoxEditField         matlab.ui.control.NumericEditField
        Ch_488_galvoyEditFieldLabel    matlab.ui.control.Label
        Ch_488_galvoyEditField         matlab.ui.control.NumericEditField
        Ch_561_galvoxEditFieldLabel    matlab.ui.control.Label
        Ch_561_galvoxEditField         matlab.ui.control.NumericEditField
        Ch_561_galvoyEditField_2Label  matlab.ui.control.Label
        Ch_561_galvoyEditField         matlab.ui.control.NumericEditField
        Ch_640_galvoxEditFieldLabel    matlab.ui.control.Label
        Ch_640_galvoxEditField         matlab.ui.control.NumericEditField
        Ch_640_galvoyEditField_2Label  matlab.ui.control.Label
        Ch_640_galvoyEditField         matlab.ui.control.NumericEditField
        TakingimagesonMbyNtileswithzstacksPanel  matlab.ui.container.Panel
        movetotileSpinnerLabel         matlab.ui.control.Label
        movetotileSpinner              matlab.ui.control.Spinner
        VerticaltileEditFieldLabel     matlab.ui.control.Label
        VerticaltileEditField          matlab.ui.control.NumericEditField
        HorizontaltileEditFieldLabel   matlab.ui.control.Label
        HorizontaltileEditField        matlab.ui.control.NumericEditField
        CheckPointsEditFieldLabel      matlab.ui.control.Label
        CheckPointsEditField           matlab.ui.control.NumericEditField
        savecheckpointsButton          matlab.ui.control.Button
        startofeachtileEditFieldLabel  matlab.ui.control.Label
        startofeachtileEditField       matlab.ui.control.NumericEditField
        saveButton_3                   matlab.ui.control.Button
        endofeachtileEditFieldLabel    matlab.ui.control.Label
        endofeachtileEditField         matlab.ui.control.NumericEditField
        saveButton_4                   matlab.ui.control.Button
        createscanpatterncenterButton  matlab.ui.control.Button
        startimagealltilesButton       matlab.ui.control.Button
        W488nmCheckBox                 matlab.ui.control.CheckBox
        W561nmCheckBox                 matlab.ui.control.CheckBox
        W640nmCheckBox                 matlab.ui.control.CheckBox
        ChoosewhichcolortoimageLabel   matlab.ui.control.Label
        DeepLabel_2                    matlab.ui.control.Label
        SurfaceLabel                   matlab.ui.control.Label
        saveoffsetfordifferentcolorButton  matlab.ui.control.Button
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        GreensignalwillblockmotionexceptinzaxisLabel  matlab.ui.control.Label
        TileStatesTextAreaLabel        matlab.ui.control.Label
        TileStatesTextArea             matlab.ui.control.TextArea
        z_distanceumLabel              matlab.ui.control.Label
        zstack_distanceum              matlab.ui.control.NumericEditField
        nm_DEditFieldLabel             matlab.ui.control.Label
        nm_D488                        matlab.ui.control.NumericEditField
        nm_DEditField_2Label           matlab.ui.control.Label
        nm_D561                        matlab.ui.control.NumericEditField
        nm_DEditFieldLabel_2           matlab.ui.control.Label
        nm_D640                        matlab.ui.control.NumericEditField
        save_DButton                   matlab.ui.control.Button
        AutoStartEndButton             matlab.ui.control.Button
        ExcitionLensandDetectionLensPanel  matlab.ui.container.Panel
        leftButtonExt                  matlab.ui.control.Button
        rightButtonExt                 matlab.ui.control.Button
        leftButtonObj                  matlab.ui.control.Button
        rightButtonObj                 matlab.ui.control.Button
        ExtStepsizeDropDownLabel       matlab.ui.control.Label
        ExtStepsizeDropDown            matlab.ui.control.DropDown
        ObjStepsizeDropDownLabel       matlab.ui.control.Label
        ObjStepsizeDropDown            matlab.ui.control.DropDown
        PaintModePanel                 matlab.ui.container.Panel
        PaintButton                    matlab.ui.control.StateButton
        UpButton                       matlab.ui.control.Button
        DownButton                     matlab.ui.control.Button
        LeftButton                     matlab.ui.control.Button
        RightButton                    matlab.ui.control.Button
        SavepaintButton                matlab.ui.control.Button
        RollSliderLabel                matlab.ui.control.Label
        RollSlider                     matlab.ui.control.Slider
        RollScanButton                 matlab.ui.control.StateButton
        imageparametersPanel           matlab.ui.container.Panel
        Power_561EditFieldLabel        matlab.ui.control.Label
        Power_561EditField             matlab.ui.control.NumericEditField
        Exposure_561EditFieldLabel     matlab.ui.control.Label
        Exposure_561EditField          matlab.ui.control.NumericEditField
        Power_640EditFieldLabel        matlab.ui.control.Label
        Power_640EditField             matlab.ui.control.NumericEditField
        Exposure_640EditFieldLabel     matlab.ui.control.Label
        Exposure_640EditField          matlab.ui.control.NumericEditField
        Power_488EditFieldLabel        matlab.ui.control.Label
        Power_488EditField             matlab.ui.control.NumericEditField
        Exposure_488EditFieldLabel     matlab.ui.control.Label
        Exposure_488EditField          matlab.ui.control.NumericEditField
        Exposure_timeEditFieldLabel    matlab.ui.control.Label
        Exposure_timeEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
         % Description
        
        f;
        
        paint;
        paint_initx;paint_inity;paint_initz;
        paint_init_image;
        allLasers;
        myhandles;
        counter;
        saveImage;
        saveImage_twopath;
        Stage_step_X;
        Stage_step_Y;
        Stage_step_Z;
        Stage_step_T;
        AFG_step;
        AFG_voltage1;AFG_voltage2;
    end
    
    properties (Access = public)
        % Description
        % two path galvo voltage
        galvo_X1voltage;
        galvo_Y1voltage;
        galvo_Z1voltage;
        
        galvo_X2voltage;
        galvo_Y2voltage;
        galvo_Z2voltage;
        
        % 
        % position of ext1,ext2,det
        ExtLens1_pos;ExtLens2_pos;DetLens_pos;
        
        % position of table
        state_path;state_motor;state_stage;state_afg;state_offset;
        
        % voltage of AFG
        afg1_offset,afg1_top,afg1_bottom;
        afg2_offset,afg2_top,afg2_bottom;
        % intensity profile
        draw_intensity_profile;
        
        expousreTime;
        stepSizeExt;
        stepSizeObj;
        imagecontrast_change;
        current_path;
        controlParameters;
        Shutterstate1;
        Shutterstate2;
        checkpoints_valuelist;
        checkpoints_index;
        zstacksposs;
        xinitPos;yinitPos;zinitPos;
        xinitPos1;yinitPos1;zinitPos1;
        xinitGalvo;yinitGalvo;zinitGalvo;   
        start_3D;
        start_correct;
        allcheckpoints;
        xyzScanArray;
        scanrow;scancol;scanindex;
        dirname;
        waitbar;
        wait_status;
        checkpoints_onetile;onetile_index;
        checkpoints_zpos;
        checkpoints_multipletile;tile_index;
        xyzScanArray_status;
        exposure_time;
    end
    
    methods (Access = private)
        
        function results = moveprevioustile(app)
            value = app.movetotileSpinner.Value;
            app.movecheckpoints1x6Spinner.Value = 0;
            if app.startofeachtileEditField.Value == 0 || app.endofeachtileEditField.Value == 0
                app.movetotileSpinner.Value = 0;
                f=msgbox('Please save the start and end points.','warning');
                return;
            end
            if abs(app.startofeachtileEditField.Value-app.endofeachtileEditField.Value)<100
                app.movetotileSpinner.Value = 0;
                f=msgbox('The distance between start and end point is too small.','warning');
                return;
            end
            if app.xyzScanArray_status == false
                app.movetotileSpinner.Value = 0;
                f=msgbox('please run create scan pattern to define position of each tile.','warning');
                return;
            end
            if value <= 1
                %app.movetotileSpinner.Value=1;
                return;
            elseif value>size(app.xyzScanArray,2)
                app.movetotileSpinner.Value=size(app.xyzScanArray,2);
                return;
            end
            app.movetotileSpinner.Value = app.movetotileSpinner.Value - 1;
            app.tile_index = app.movetotileSpinner.Value;
            [xPos, yPos, zPos] = getXYZposition(app.controlParameters.MMC);
            dx = app.xyzScanArray(1,app.tile_index) - xPos; dy = app.xyzScanArray(2,app.tile_index) - yPos; dz = app.xyzScanArray(3,app.tile_index) - zPos;
            SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
        end
        
        function results = movenexttile(app)
            value = app.movetotileSpinner.Value;
            app.movecheckpoints1x6Spinner.Value = 0;
            if app.startofeachtileEditField.Value == 0 || app.endofeachtileEditField.Value == 0
                app.movetotileSpinner.Value = 0;
                f=msgbox('Please save the start and end points.','warning');
                return;
            end
            if abs(app.startofeachtileEditField.Value-app.endofeachtileEditField.Value)<100
                app.movetotileSpinner.Value = 0;
                f=msgbox('The distance between start and end point is too small.','warning');
                return;
            end
            if app.xyzScanArray_status == false
                app.movetotileSpinner.Value = 0;
                f=msgbox('please run create scan pattern to define position of each tile.','warning');
                return;
            end
            if value < 0
                app.movetotileSpinner.Value=1;
                return;
            elseif value>=size(app.xyzScanArray,2)
                app.movetotileSpinner.Value=size(app.xyzScanArray,2);
                return;
            end
            app.movetotileSpinner.Value = app.movetotileSpinner.Value + 1;
            app.tile_index = app.movetotileSpinner.Value;
            [xPos, yPos, zPos] = getXYZposition(app.controlParameters.MMC);
            dx = app.xyzScanArray(1,app.tile_index) - xPos; dy = app.xyzScanArray(2,app.tile_index) - yPos; dz = app.xyzScanArray(3,app.tile_index) - zPos;
            SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
        end
        
        function results = movenextcheck(app)
            value = app.movecheckpoints1x6Spinner.Value;
            if value >= app.CheckPointsEditField.Value
                app.movecheckpoints1x6Spinner.Value = app.CheckPointsEditField.Value;
                return;
            elseif value < 0
                app.movecheckpoints1x6Spinner.Value = 1;
                return;
            end
            if app.startofeachtileEditField.Value == 0 || app.endofeachtileEditField.Value == 0
                f=msgbox('Please save the start and end points.','warning');
                return;
            end
            if abs(app.startofeachtileEditField.Value-app.endofeachtileEditField.Value)<60
                f=msgbox('The distance between start and end point is too small.','warning');
                return;
            end
            if app.startofeachtileEditField.Value > app.endofeachtileEditField.Value
                f=msgbox('the start of tile should be deep inside the sample.','warning');
                return;
            end
            if app.endofeachtileEditField.Value - app.startofeachtileEditField.Value < 100
                f=msgbox('the distance betwween start and end is too small');
                return;
            end
            app.movecheckpoints1x6Spinner.Value = app.movecheckpoints1x6Spinner.Value + 1;
            app.checkpoints_zpos = round(linspace(round(app.startofeachtileEditField.Value,-1)+40,round(app.endofeachtileEditField.Value,-1)-40,app.CheckPointsEditField.Value));
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            dy = app.checkpoints_zpos(app.movecheckpoints1x6Spinner.Value) - yPos;
            SetRelativePos(app.controlParameters.MMC,0,dy,0);
        end
        
        function results = moveprevcheck(app)
            value = app.movecheckpoints1x6Spinner.Value;
            if value > app.CheckPointsEditField.Value
                app.movecheckpoints1x6Spinner.Value = app.CheckPointsEditField.Value;
                return;
            elseif value <= 1
                %app.movecheckpoints1x6Spinner.Value = 1;
                return;
            end
            if app.startofeachtileEditField.Value == 0 || app.endofeachtileEditField.Value == 0
                f=msgbox('Please save the start and end points.','warning');
                return;
            end
            if abs(app.startofeachtileEditField.Value-app.endofeachtileEditField.Value)<60
                f=msgbox('The distance between start and end point is too small.','warning');
                return;
            end
            if app.startofeachtileEditField.Value > app.endofeachtileEditField.Value
                f=msgbox('the start of tile should be deep inside the sample.','warning');
                return;
            end
            if app.endofeachtileEditField.Value - app.startofeachtileEditField.Value < 100
                f=msgbox('the distance betwween start and end is too small');
                return;
            end
            app.movecheckpoints1x6Spinner.Value = app.movecheckpoints1x6Spinner.Value - 1;
            app.checkpoints_zpos = round(linspace(round(app.startofeachtileEditField.Value,-1)+40,round(app.endofeachtileEditField.Value,-1)-40,app.CheckPointsEditField.Value));
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            dy = app.checkpoints_zpos(app.movecheckpoints1x6Spinner.Value) - yPos;
            SetRelativePos(app.controlParameters.MMC,0,dy,0);
        end
        
        function results = auto_start_end(app)
            %Ryan Garry 01-07-2025
            intensity_threshold = 1000; % assume background 200 with filter and 1mW laser;
            % how does background intensity change with filters and laser power?
            % (it shouldn't be affected by the laser power because of filter); 
            % 1% intensity for a 16 bit camera (2^16-1 = 65535); 
            % (2^16-1)*0.01 = 655
            pixel_threshold = 2500; % minimum # of pixels 2048 x 2048 image; 2048^2 pixels = 4194304; 2500
            y_step_size = 100; % um
            num_tiles = size(app.xyzScanArray,2);
            max_num_steps = 100;
            max_empty_count = 3;
            min_y_width = 100; % start-end y pos
            max_yPos = 3220;
            min_yPos = -8395;
            
            % add function to check that laser is on, filter is set, and
            % afg scan is enabled
            
            if app.xyzScanArray_status == false
                app.movetotileSpinner.Value = 0;
                f=msgbox('Please run create scan pattern to define position of each tile.','warning');
                return;
            end
            
            if convertCharsToStrings(app.Lightwavelight.Value) == 'None'
                f=msgbox('Please choose laser.','warning');
                return;
            end

            
            if convertCharsToStrings(app.Filterwheel.Value) == 'None'
                f=msgbox('Please choose filter.','warning');
                return;
            end
            
            if app.AFG_scanButton.Value == 0
                f=msgbox('Please enable AFG.','warning');
                return;
            end
            
            app.waitbar = uifigure;
            app.wait_status = uiprogressdlg(app.waitbar,'Title','Auto Start End','Message','Close curtains and dim lighting','Cancelable','on');
            
            app.f = figure;
            app.f.Position = [100 100 1024 1024];
            set(app.f,'toolbar','figure');
            hAxes = subplot(1,1,1);
            
            % loop through each tile 
            for i = 1:num_tiles
                app.movetotileSpinner.Value = i; 
                movetotile(app); % moves to the center of tile i
                app.wait_status.Value = i/num_tiles;
                app.wait_status.Message = ['Tile ',num2str(i)];
                
                %[x_center,y_center,z_center]=getXYZposition(app.controlParameters.MMC);
                empty_counter = 0;
                saved_yPos = 'N';
                saved_img = 'N';
                % positive loop through tile from center
                for j = 1:max_num_steps
                    % update waitbar
                    if app.wait_status.CancelRequested
                        close(app.waitbar);
                        close(app.wait_status);
                        return;
                    end
                    image = 'N';
                    image = requestoneimagefromcamera(app); % capture image
                    
                    figure(2)
                    histogram(image);
                    %pause(1)
                    
                    pixels_above_threshold = sum(image(:) > intensity_threshold);
                    if pixels_above_threshold < pixel_threshold
                        % assume the image is empty
                        % increment empty counter by 1
                        empty_counter = empty_counter + 1;
                    else
                        % assume it was just a gap and reset empty counter
                        empty_counter = 0;   
                        saved_yPos = 'N';
                    end
                    if empty_counter == 1
                        %save ypos at first empty image
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        saved_yPos = yPos;
                        saved_image = image;
                    end
                    if empty_counter >= max_empty_count
                        app.endofeachtileEditField.Value = saved_yPos; % end is more positive than start
                        %figure()
                        hImage = imshow(saved_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                        set(hImage,'CData',saved_image);
                        break
                    end
                    % if no empty images are found
                    if empty_counter == 0 && j == max_num_steps
                        f=msgbox('More steps are needed to find tissue boundaries. Please increase max_num_steps and try again.','warning');
                        %return
                    end
                    
                    
                    [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                    if yPos > max_yPos-y_step_size
                        %then it will move past the safety range
                        f=msgbox(sprintf('You are moving too close to the lens for tile %d', i),'warning');
                        saved_yPos = yPos;
                        saved_img = image;
                        app.endofeachtileEditField.Value = saved_yPos;
                        figure()
                        imshow(saved_image)
                        title(sprintf("Tile: %d, forwards",i))
                        break
                    end
                    % relative move by one y step size
                    SetRelativePos(app.controlParameters.MMC,0,y_step_size,0);
                    
                end
                % repeat for loop from y center but with -step_size
                % set position to tile center pos
                movetotile(app); % moves to the center of tile i
                %[xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                %dx = x_center - xPos; dy = y_center - yPos; dz = z_center - zPos;
                %SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                % negative loop through tile from center
                empty_counter = 0;
                saved_yPos = 'N';
                saved_img = 'N';
                for j = 1:max_num_steps
                    if app.wait_status.CancelRequested
                        close(app.waitbar);
                        close(app.wait_status);
                        return;
                    end
                    image = requestoneimagefromcamera(app); % capture image
                    figure(2)
                    histogram(image);
                    %pause(1)
                    pixels_above_threshold = sum(image(:) > intensity_threshold);
                    if pixels_above_threshold < pixel_threshold
                        % assume the image is empty
                        % increment empty counter by 1
                        empty_counter = empty_counter + 1;
                    else
                        % assume it was just a gap and reset empty counter
                        empty_counter = 0;   
                        saved_yPos = 'N';
                    end
                    if empty_counter == 1
                        %save ypos at first empty image
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        saved_yPos = yPos;
                        saved_image = image;
                    end
                    if empty_counter >= max_empty_count
                        app.startofeachtileEditField.Value = saved_yPos; % end is more positive than start
                        figure()
                        imshow(saved_img)
                        title(sprintf("Tile: %d, backwards",i))
                        break
                    end
                    % if no empty images are found
                    if empty_counter == 0 && j == max_num_steps
                        f=msgbox('More steps are need to find tissue boundaries. Please increase num_steps and try again.','warning');
                        return
                    end
                    
                    [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                    if yPos < min_yPos+y_step_size
                        %then it will move past the safety range
                        f=msgbox(sprintf('You are moving too far away from the lens for tile %d', i),'warning');
                        saved_yPos = yPos;
                        app.endofeachtileEditField.Value = saved_yPos;
                        saved_image = image;
                        figure()
                        imshow(saved_img)
                        title(sprintf("Tile: %d, backwards",i))
                        break
                    end
                    % relative move by one -y step size
                    SetRelativePos(app.controlParameters.MMC,0,-y_step_size,0);
                    
                end
                
                % ensure that there is atleast 100um between start and end
                % required by movetotile()
                % if only empty images are found in a tile give 100um start
                % end spacing
                if app.endofeachtileEditField.Value - app.startofeachtileEditField.Value < min_y_width
                    app.startofeachtileEditField.Value = app.endofeachtileEditField.Value - min_y_width - 1;
                end

                % now press save checkpoint
                savecheckpoint(app)
                
            end
            close(app.waitbar);
            close(app.wait_status);
        end
        
        function results = movetotile(app)
              value = app.movetotileSpinner.Value;
            if value == 1
                % do nothing
            elseif app.startofeachtileEditField.Value == 0 || app.endofeachtileEditField.Value == 0
                app.movetotileSpinner.Value = 0;
                f=msgbox('Please save the start and end points.','warning');
                return;
            elseif abs(app.startofeachtileEditField.Value-app.endofeachtileEditField.Value)<100
                app.movetotileSpinner.Value = 0;
                f=msgbox('The distance between start and end point is too small.','warning');
                return;
            end
            if app.xyzScanArray_status == false
                app.movetotileSpinner.Value = 0;
                f=msgbox('please run create scan pattern to define position of each tile.','warning');
                return;
            end
            if value < 1
                app.movetotileSpinner.Value=1;
                return;
            elseif value>size(app.xyzScanArray,2)
                app.movetotileSpinner.Value=size(app.xyzScanArray,2);
                return;
            end
            app.tile_index = app.movetotileSpinner.Value;
            [xPos, yPos, zPos] = getXYZposition(app.controlParameters.MMC);
            dx = app.xyzScanArray(1,app.tile_index) - xPos; dy = app.xyzScanArray(2,app.tile_index) - yPos; dz = app.xyzScanArray(3,app.tile_index) - zPos;
            SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
        end
        
        function result = savecheckpoint(app)
            if app.VerticaltileEditField.Value == 0 || app.HorizontaltileEditField.Value == 0 || app.CheckPointsEditField.Value==0
                f=msgbox('please set necessary parameters.','warning');
                return;
            end

            if app.movetotileSpinner.Value == 0
                f=msgbox('please move to the first tile.','warning');
                return;
            end

            if app.startofeachtileEditField.Value > app.endofeachtileEditField.Value
                f=msgbox('the start of tile should be deep inside the sample.','warning');
                return;
            end
            if convertCharsToStrings(app.Lightwavelight.Value) == 'None'
                f=msgbox('Please choose laser.','warning');
                return;
            end
            tile_index = app.movetotileSpinner.Value;
            %checkpoints_index = app.movecheckpoints1x6Spinner.Value;
            
%             app.checkpoints_multipletile(tile_index,checkpoints_index).current_path = app.current_path;
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X = app.state_path.Galvo_X(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y = app.state_path.Galvo_Y(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z = app.state_path.Galvo_Z(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens = app.state_motor.MotorPosition_mm_(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens = app.state_motor.MotorPosition_mm_(3);
            %app.checkpoints_multipletile(tile_index,checkpoints_index).wavelength = app.Lightwavelight.Value;
            app.checkpoints_multipletile(tile_index,1).current_path = app.current_path;
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.checkpoints_multipletile(tile_index,1).xPos = xPos;
            app.checkpoints_multipletile(tile_index,1).yPos = yPos;
            app.checkpoints_multipletile(tile_index,1).zPos = zPos;
            %app.checkpoints_multipletile(tile_index,checkpoints_index).saved = true;
            app.checkpoints_multipletile(tile_index,1).start_pos = round(app.startofeachtileEditField.Value,-1);
            app.checkpoints_multipletile(tile_index,1).end_pos = round(app.endofeachtileEditField.Value,-1);
            app.checkpoints_multipletile(tile_index,1).saved = true;
%             if convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).saved_488nm = true;
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X_488nm = app.state_path.Galvo_X(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y_488nm = app.state_path.Galvo_Y(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z_488nm = app.state_path.Galvo_Z(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens_488nm = app.state_motor.MotorPosition_mm_(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens_488nm = app.state_motor.MotorPosition_mm_(3);
%             elseif convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).saved_561nm = true;
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X_561nm = app.state_path.Galvo_X(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y_561nm = app.state_path.Galvo_Y(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z_561nm = app.state_path.Galvo_Z(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens_561nm = app.state_motor.MotorPosition_mm_(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens_561nm = app.state_motor.MotorPosition_mm_(3);
%                 %app.checkpoints_multipletile(tile_index,checkpoints_index).wavelength = app.Lightwavelight.Value;
%             elseif convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).saved_640nm = true;
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X_640nm = app.state_path.Galvo_X(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y_640nm = app.state_path.Galvo_Y(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z_640nm = app.state_path.Galvo_Z(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens_640nm = app.state_motor.MotorPosition_mm_(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens_640nm = app.state_motor.MotorPosition_mm_(3);
%             end


            % check if all checkpoints are saved
            saved_total = 0;
            for i = 1:size(app.xyzScanArray,2)
                if app.checkpoints_multipletile(i,1).saved == true
                    saved_total = saved_total + 1; 
                end
            end
            
            save_all= 'No';
            if saved_total == size(app.xyzScanArray,2)
                save_all = "Yes";
                local_variable = app.checkpoints_multipletile;
                save('scanpattern.mat','local_variable');
            end

            
            %char_text = 'Tile '+ char(num2str(tile_index)) + '/' + char(num2str(size(app.xyzScanArray,2)))+'   '+char(num2str(save));
            %text_str = "Tile: " + num2str(tile_index) + '/' + num2str(size(app.xyzScanArray,2)) + " save status:" + num2str(save) + '   save all checkpoints? ' + save_all;
            text = ["Tile: " + num2str(tile_index) + '/' + num2str(size(app.xyzScanArray,2)) + ' current tile' + " save status:" + num2str(app.checkpoints_multipletile(tile_index,1).saved) + '   save all checkpoints? ' + save_all];
            
            start_min = min([app.checkpoints_multipletile(1:tile_index,1).start_pos]);
            end_max = max([app.checkpoints_multipletile(1:tile_index,1).end_pos]);
            text_maxminpos = ['min start pos: ',num2str(start_min),' max end pos: ',num2str(end_max)];
            %app.StateofTilesEditField.Value  = text_str;
            app.TileStatesTextArea.Value{1} = char(text);
            app.TileStatesTextArea.Value{2} = char(text_maxminpos);
        end
    end
    
    methods (Access = public)
        
        function Image = requestoneimagefromcamera(app)
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    Image = rot90(image);                    
                    break;
                 end
            end
            
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            
        end
        
        
        function results = dynamic_focus(app,channel)
            if channel == '488'
                detection_pos = app.nm_D488.Value;
            elseif channel == '561'
                detection_pos = app.nm_D561.Value;
            elseif channel == '640'
                detection_pos = app.nm_D640.Value;
            end
            detect_pos = [-0.020:0.004:0.020];
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            pos = getpos(pos);
            current_detection_pos = str2num(pos);
            for i=1:length(detect_pos)
                fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(i)+current_detection_pos)]);
                pause(0.5);
                if i==1
                    fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(i)+current_detection_pos)]);
                    pause(0.5);
                end
                img = requestoneimagefromcamera(app);
                I(:,:,i) = requestoneimagefromcamera(app);
            end
            for i=1:length(detect_pos)
                %FM(i) = wentropy(I(1024-500:1024+500,1024-500:1024+500,i), 'shannon');
                %FM(i) = DCTS(app,I(1024-500:1024+500,1024-500:1024+500,i));
                FM(i) = wentropy(double(I(1024-500:1024+500,1024-500:1024+500,i)),'shannon');
            end
            FM = FM/max(FM);
            grad = gradient(FM);
            if singlepeak(app,grad)
                a = 1;
                [value, index] = max(FM);
                fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(index)+current_detection_pos)]);
                disp('use autofocus');
                disp(detect_pos(index)+current_detection_pos);
                pause(0.5);
            else
                fprintf(app.controlParameters.sDetLens,['1PA',num2str(current_detection_pos)]);
                disp(current_detection_pos);
                pause(0.5); 
            end
        end
        
        
        function result = singlepeak(app,grad)
            pos_index = find(grad>=0);
            neg_index = find(grad<0);
            if isempty(pos_index) || isempty(neg_index)
                result = false;
                return;
            else
                if max(pos_index) < min(neg_index)
                    result = true;
                    return;
                else
                    result = false;
                    return;
                end
            end
        end
        
        
        function results = DCTS(app,img)
            r = 800;
            img_filt = imgaussfilt(img);
            img_DCT = dct2(img_filt);
            L2 = norm(img_DCT);
            value = 0;
            for i=1:r
                for j=1:r
                    if i^2+j^2<r^2
                        val = img_DCT(i,j)/L2;
                        value = value + abs(val)*abslog2(app,val);
                    else
                        continue;
                    end
                end
            end
            results = value*-2/(r^2);
        end
        
        
        function result = abslog2(app,x)
            if x > 0
                result = log2(x);
            elseif x < 0
                result = log2(-x);
            elseif x == 0
                result = 0;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('D:\lightSheetsourcecode_twopath\AfgCode','D:\lightSheetsourcecode_twopath\DCMotor',...
    'D:\lightSheetsourcecode_twopath\FilterWheel','D:\lightSheetsourcecode_twopath\OBISGeneral',...
    'D:\lightSheetsourcecode_twopath\UsefullFunctions\CalibrationFunctions','D:\lightSheetsourcecode_twopath\UsefullFunctions\PickTiles',...
    'D:\lightSheetsourcecode_twopath\MMcodeOrcaV2\selfmade',...
    'D:\lightSheetsourcecode_twopath\MMcodeOrcaV2\wholezstackfiles');
            app.StatusLamp.Color = [1 0 0];
            app.controlParameters = evalin('base','controlParameters');
            app.allLasers = evalin('base','allLasers');
            app.current_path = 1;
            app.draw_intensity_profile = false;
            
            SwitchOnOffObis(app.allLasers(1).serialPort,0);
            SwitchOnOffObis(app.allLasers(2).serialPort,0);
            SwitchOnOffObis(app.allLasers(3).serialPort,0);
            
            app.state_stage = readtable('states_record.xlsx','Sheet',1);
            app.state_path = readtable('states_record.xlsx','Sheet',2);
            app.state_motor = readtable('states_record.xlsx','Sheet',3);
            app.state_afg = readtable('states_record.xlsx','Sheet',4);
            app.state_offset = readtable('states_record.xlsx','Sheet',5);
            
            
            app.galvo_X1voltage = 0;app.galvo_X2voltage = 0;
            app.galvo_Y1voltage = 0;app.galvo_Y2voltage = 0;
            app.galvo_Z1voltage = 0;app.galvo_Z2voltage = 0;
            app.expousreTime = 100;
            app.stepSizeExt = 0.1;
            app.stepSizeObj = 0.01;
            app.Shutterstate1=0;app.Shutterstate2=0;
            
            app.imagecontrast_change = true;
            app.expousreTime = 100;%ms
            app.saveImage = false;
            app.saveImage_twopath = false;
            
            app.Stage_step_X = 10;
            app.Stage_step_Y = 10;
            app.Stage_step_Z = 10;
            app.Stage_step_T = 10;
            
            %app.X_pos.Value = app.controlParameters.MMC.getXPosition();
            %app.Y_pos.Value = app.controlParameters.MMC.getYPosition();
            %app.Z_pos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            %app.T_pos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
            
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.state_stage.Y = app.controlParameters.MMC.getYPosition();
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.state_stage.T = app.controlParameters.MMC.getPosition('ZStage:T:32');
            
            app.AFG_step = 1;
            app.afg1_offset = app.state_afg.Offset_mv_(1);app.afg2_offset = app.state_afg.Offset_mv_(2);            
            app.afg1_top = app.state_afg.Top_mv_(1);app.afg2_top = app.state_afg.Top_mv_(2);            
            app.afg1_bottom = app.state_afg.Bottom_mv_(1);app.afg2_bottom = app.state_afg.Bottom_mv_(2);
            
            %app.afg1_middle = 0;app.afg2_middle = 0;
            fwrite(app.controlParameters.afg, ':source1:function DC');
            fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.afg1_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output1 on;');
            
            fwrite(app.controlParameters.afg, ':source2:function DC');
            fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.afg2_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output2 on;');
            
            
            %dcValueAfterWrite1 = query(app.controlParameters.afg, ':source1:voltage:offset?');
            %dcValueAfterWrite2 = query(app.controlParameters.afg, ':source2:voltage:offset?');
            %state_table.X{6} =  dcValueAfterWrite1;state_table.X{7} =  dcValueAfterWrite2;


            %app.Z_stack.Value = 1;
            % get the position of ext and obj lens
            
            fprintf(app.controlParameters.sExtLens1,'1TP?');
            pos = fscanf(app.controlParameters.sExtLens1);
            app.ExtLens1_pos = getpos(pos);
            app.state_motor.MotorPosition_mm_(1) = str2num(app.ExtLens1_pos);
            
            %app.ExtPos.Value = str2num(pos);
            app.dirname = '';
            
%             fprintf(app.controlParameters.sExtLens2,'1TP?');
%             pos = fscanf(app.controlParameters.sExtLens2);
%             app.ExtLens2_pos = getpos(pos);
%             app.state_motor.MotorPosition_mm_(2) = str2num(app.ExtLens2_pos);
            
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            app.DetLens_pos = getpos(pos);
            app.state_motor.MotorPosition_mm_(3) = str2num(app.DetLens_pos);
            
            %app.ObjPos.Value = str2num(pos);
            app.start_3D = false;
            app.start_correct = false;
            
            
            app.UITable_stage.Data = app.state_stage;
            app.UITable_path.Data = app.state_path;
            app.UITable_motor.Data = app.state_motor;
            app.UITable_afg.Data = app.state_afg;
            app.UITable_offset.Data = app.state_offset;
            
            % set the framerate of camera
            app.controlParameters.MMC.stopSequenceAcquisition;
            expTimeForScan = 100;
            app.controlParameters.MMC.setProperty(app.controlParameters.cameraLabel,'Exposure', expTimeForScan);
            app.controlParameters.MMC.startSequenceAcquisition(5, 0, false);
            Value = 1/(str2num(app.controlParameters.MMC.getProperty(app.controlParameters.cameraLabel, 'Exposure'))/1000);
            
            app.controlParameters.MMC.clearCircularBuffer;
            app.onetile_index = 0;
            %app.waitbar = app.waitbar;
            %d = uiprogressdlg(app.waitbar,'Title','please wait','Message','open it');

            app.TileStatesTextArea.Value{1} = '';
            app.TileStatesTextArea.Value{2} = '';
            app.TileStatesTextArea.Value{3} = '';
            
            app.xyzScanArray_status = false;
            %app.StateofTilesEditField.Value = num2str(a);
            %app.waitbar = uifigure;
            % keep the record of max/min z max/min x max/min y
            % y_near_the camera 6420um
            % y_farfrom_the camera 22000um
            % x_top -19500um
            % x_bottom 6200um
            % z right -21500
            % z left 
        end

        % Value changed function: ShutterButton
        function ShutterButtonValueChanged(app, event)
            app.Lightwavelight.Value
            value = app.ShutterButton.Value;
            if app.current_path == 1
                if value == 1
                    app.Shutterstate1 = 4;
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                    if convertCharsToStrings(app.Lightwavelight.Value) == 'None'
                        SwitchOnOffObis(app.allLasers(1).serialPort,0);
                        SwitchOnOffObis(app.allLasers(2).serialPort,0);
                        SwitchOnOffObis(app.allLasers(3).serialPort,0);                    
                    elseif convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                        outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_488_galvoxEditField.Value,app.Ch_488_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                    elseif convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                        outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_561_galvoxEditField.Value,app.Ch_561_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                    elseif convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                        outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_640_galvoxEditField.Value,app.Ch_640_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);            
                    end
                else
                    app.Shutterstate1 = 0;
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                end
                
            elseif app.current_path == 2
                a=1;
            end
        end

        % Value changed function: Lightwavelight
        function LightwavelightValueChanged(app, event)
            value = app.Lightwavelight.Value;
            % reset the galvo x and y sliders
            
                if convertCharsToStrings(value) == 'None'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);                    
                elseif convertCharsToStrings(value) == '488nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,1);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_488_galvoxEditField.Value,app.Ch_488_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                elseif convertCharsToStrings(value) == '561nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,1);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_561_galvoxEditField.Value,app.Ch_561_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                elseif convertCharsToStrings(value) == '640nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,1);
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_640_galvoxEditField.Value,app.Ch_640_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                    
                end                
        end

        % Value changed function: LaserPower
        function LaserPowerValueChanged(app, event)
            value = app.LaserPower.Value;
            if value > 20
                value = 20;
            elseif value < 0
                value = 0;
            end
            for ii = 1:numel(app.allLasers)
                ChangePowerObis(app.allLasers(ii).serialPort,value,app.allLasers(ii).maxPower);
            end
        end

        % Value changed function: galvo_XSlider
        function galvo_XSliderValueChanged(app, event)
            value = app.galvo_XSlider.Value;

            if app.current_path == 1
                app.galvo_X1voltage = value;
                app.state_path.Galvo_X(1) = value;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            elseif app.current_path == 2
                a=1;
            end
            app.UITable_path.Data = app.state_path;
        end

        % Value changed function: galvo_YSlider
        function galvo_YSliderValueChanged(app, event)
            value = app.galvo_YSlider.Value;

            if app.current_path == 1
                app.galvo_Y1voltage = value;
                app.state_path.Galvo_Y(1) = value;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            elseif app.current_path == 2
                a=1;
            end
            app.UITable_path.Data = app.state_path;
        end

        % Button pushed function: Reset_galvo_XYButton
        function Reset_galvo_XYButtonPushed(app, event)
            app.galvo_XSlider.Value = 0;
            app.galvo_YSlider.Value = 0;
            if app.current_path == 1
                app.galvo_X1voltage=0;app.state_path.Galvo_X(1) = 0;
                app.galvo_Y1voltage=0;app.state_path.Galvo_Y(1) = 0;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            elseif app.current_path == 2
                a=1;
            end
            app.UITable_path.Data = app.state_path;
            
        end

        % Button pushed function: EnableCam
        function EnableCamButtonPushed(app, event)
            
            % open the shutter01
            if app.current_path == 1
                app.Shutterstate1 = 4;app.Shutterstate2=0;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                
            
                if convertCharsToStrings(app.Lightwavelight.Value) == 'None'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);                    
                elseif convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,1);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_488_galvoxEditField.Value,app.Ch_488_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                elseif convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,1);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_561_galvoxEditField.Value,app.Ch_561_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                elseif convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,1);
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.Ch_640_galvoxEditField.Value,app.Ch_640_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);            
                end 
            elseif app.current_path == 2
                a=1;
            end
            fwrite(app.controlParameters.afg, ':source2:function DC');
            fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.afg2_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output2 on;');
            
            app.f = figure;
            app.f.Position = [100 100 1024 1024];
            set(app.f,'toolbar','figure');
            hAxes = subplot(1,1,1);
            
            app.controlParameters.MMC.stopSequenceAcquisition;
            %Make sure the buffer is empty
            app.controlParameters.MMC.clearCircularBuffer;
            %Debug
            app.controlParameters.MMC.setCircularBufferMemoryFootprint(1024*10);
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',app.Exposure_timeEditField.Value);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
            
            if checkBuffer==0
                pause(0.5);
            end
            
            image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
            width = app.controlParameters.MMC.getImageWidth();
            height = app.controlParameters.MMC.getImageHeight();
            pixelType = 'uint16';
            image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
            image = reshape(image, [width, height]); % image should be interpreted as a 2D array
            image = rot90(image);
            hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            %colormapeditor;
            
            app.myhandles = guihandles(app.f);
            %app.myhandles.shutters = app.controlParameters.shutters;
            app.myhandles.continue = true;
            %app.myhandles.textbox_handle = textbox_handle;
            magnify(); % magnifty the camear window
            while (app.myhandles.continue)
                guidata(app.f,app.myhandles);
                if app.imagecontrast_change == true
                    app.imagecontrast_change = false;
                    hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                end
                
                checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
                if checkBuffer==0
                    pause(0.6);
                end
                startTime = tic;
                image = app.controlParameters.MMC.getLastImage();
                image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                image = rot90(image);
                image = image(:,end:-1:1);
                hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                set(hImage,'CData',image);
                current_color = app.Lightwavelight.Value;
                                
                if (app.saveImage)
                    app.saveImage = false;
                    nameOfFile = [app.dirname,'/',num2str(app.state_stage.X),'_',...
                    num2str(app.state_stage.Y),'_',num2str(app.state_stage.Z),'_',app.LIghtpathDropDown.Value,'_',current_color,'_',date,'.tiff'];
                    app.counter = app.counter + 1;
                    imwrite(image, nameOfFile);    
                    guidata(app.f,app.myhandles);
                end
                
                elTime = toc(startTime);
                if (elTime <= (app.expousreTime/1000))
                    pause(app.expousreTime/1000 - elTime);                
                else
                    pause(0.15);
                end
                %app.myhandles = guidata(app.f);
            end
            
        end

        % Button pushed function: DisableCam
        function DisableCamButtonPushed(app, event)
            app.myhandles.continue = false; 
            close(app.f);
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            % close the shutter
            app.Shutterstate1 = 0;
            app.Shutterstate2 = 0;
            outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            
        end

        % Value changed function: ExtStepsizeDropDown
        function ExtStepsizeDropDownValueChanged(app, event)
            value = app.ExtStepsizeDropDown.Value;
            
            if str2num(value) == 0.1
                app.stepSizeExt = 0.1;
            end
            if str2num(value) == 0.01
                app.stepSizeExt = 0.01;
            end
            if str2num(value) == 0.001
                app.stepSizeExt = 0.001;
            end
        end

        % Button pushed function: leftButtonExt
        function leftButtonExtPushed(app, event)
            if app.current_path == 1
                fprintf(app.controlParameters.sExtLens1,['1PR',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens1,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens1);
            else
                fprintf(app.controlParameters.sExtLens2,['1PR',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens2,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens2);
            end
            pos = getpos(pos);
            %app.ExtPos.Value = str2num(pos);
            if app.current_path == 1
                app.state_motor.MotorPosition_mm_(1) = str2num(pos);
            else
                app.state_motor.MotorPosition_mm_(2) = str2num(pos);
            end
            app.UITable_motor.Data = app.state_motor;
        end

        % Button pushed function: rightButtonExt
        function rightButtonExtPushed(app, event)
            if app.current_path == 1
                fprintf(app.controlParameters.sExtLens1,['1PR-',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens1,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens1);
            else
                fprintf(app.controlParameters.sExtLens2,['1PR-',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens2,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens2);
            end
            pos = getpos(pos);
            %app.ExtPos.Value = str2num(pos);
            if app.current_path == 1
                app.state_motor.MotorPosition_mm_(1) = str2num(pos);
            else
                app.state_motor.MotorPosition_mm_(2) = str2num(pos);
            end
            app.UITable_motor.Data = app.state_motor;
        end

        % Value changed function: Imagecontrast
        function ImagecontrastValueChanged(app, event)
            value = app.Imagecontrast.Value;
            app.imagecontrast_change = true;
            if value < 0 
                app.Imagecontrast.Value = 300;
            elseif value >= 65535
                app.Imagecontrast.Value = 65500;
            end
        end

        % Value changed function: Filterwheel
        function FilterwheelValueChanged(app, event)
            value = app.Filterwheel.Value;
            if convertCharsToStrings(value) == 'None'
                fprintf(app.controlParameters.sFWDet, 'pos=1');
            elseif convertCharsToStrings(value) == 'Blue'
                fprintf(app.controlParameters.sFWDet, 'pos=2'); % Filter 525/39
            elseif convertCharsToStrings(value) == 'Green'
                fprintf(app.controlParameters.sFWDet, 'pos=3'); % Filter 593/40
            elseif convertCharsToStrings(value) == 'Red'
                fprintf(app.controlParameters.sFWDet, 'pos=4'); % Filter 676/37
            end
        end

        % Button pushed function: SaveImage
        function SaveImageButtonPushed(app, event)
            if app.savepathEditField.Value == ""
                    disp('Please set the saving directory.')
                    return;
            end
            app.saveImage = true;
        end

        % Value changed function: Updown_stepDropDown
        function Updown_stepDropDownValueChanged(app, event)
            value = app.Updown_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_X = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_X = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_X = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_X = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_X = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_X = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_X = 2000;
            elseif convertCharsToStrings(value) == '1cm'
                app.Stage_step_X = 10000;
            end
        end

        % Value changed function: Inout_stepDropDown
        function Inout_stepDropDownValueChanged(app, event)
            value = app.Inout_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_Y = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_Y = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_Y = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_Y = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_Y = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_Y = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_Y = 2000;
            elseif convertCharsToStrings(value) == '1cm'
                app.Stage_step_Y = 10000;
            end
        end

        % Value changed function: Leftright_stepDropDown
        function Leftright_stepDropDownValueChanged(app, event)
            value = app.Leftright_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_Z = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_Z = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_Z = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_Z = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_Z = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_Z = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_Z = 2000;
            elseif convertCharsToStrings(value) == '1cm'
                app.Stage_step_Z = 10000;
            end
        end

        % Value changed function: Rotation_stepDropDown
        function Rotation_stepDropDownValueChanged(app, event)
            value = app.Rotation_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_T = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_T = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_T = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_T = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_T = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_T = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_T = 4000;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            current_x = app.controlParameters.MMC.getXPosition();
            
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',-app.Stage_step_X,0);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            
%             app.X_pos.Value = app.controlParameters.MMC.getXPosition();
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            current_x = app.controlParameters.MMC.getXPosition();
            
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',app.Stage_step_X,0);
            
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
%             app.X_pos.Value = app.controlParameters.MMC.getXPosition();
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
        
            current_y = app.controlParameters.MMC.getYPosition();
            
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,-app.Stage_step_Y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            %app.Y_pos.Value = app.controlParameters.MMC.getYPosition();
            app.state_stage.Y = app.controlParameters.MMC.getYPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
        
            current_y = app.controlParameters.MMC.getYPosition();
            
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,app.Stage_step_Y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            %app.Y_pos.Value = app.controlParameters.MMC.getYPosition();
            app.state_stage.Y = app.controlParameters.MMC.getYPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            
            current_z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            %app.Z_pos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            
            current_z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',-app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            %app.Z_pos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:T:32',-app.Stage_step_T);
            app.controlParameters.MMC.waitForDevice('ZStage:T:32');
            %app.T_pos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.state_stage.T = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:T:32',app.Stage_step_T);
            app.controlParameters.MMC.waitForDevice('ZStage:T:32');
            %app.T_pos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.state_stage.T = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Callback function
        function MoveandStitch_3DButtonPushed(app, event)
            pixelSizeUm = 6.5;
            tubeLensFocalDistance = 180; %mm
            objectiveDesignedTubeLensFocal = 180; %mm
            objectiveMag = 10;overlap = -95;
            downSampleFactor = 1;
            effectivePixelSize = (pixelSizeUm/objectiveMag)*(objectiveDesignedTubeLensFocal/tubeLensFocalDistance)*downSampleFactor;
            stepSizeInLateralScan = effectivePixelSize*(2048/downSampleFactor)*(100-overlap)/100;
            fwrite(app.controlParameters.afg, ':output1 on;');pause(.5);outputSingleScan(app.controlParameters.s,[4,0,0,0]);
            z_stack_step = 10;
            
            for i = 1:app.Z_stack.Value
                app.controlParameters.MMC.setRelativeXYPosition(0,z_stack_step);
                [AllImage,xyzScanArray]=MoveandStitch(app,app.Vertiles_num.Value,app.Horztiles_num.Value,stepSizeInLateralScan);
                AllImage = uint16(AllImage);
                name_of_file = ['../zstacks/cochlea/_ChenLi_cochlea_',date,num2str(i,'%04d'),'.tiff'];
                imwrite(AllImage,name_of_file);
            end
        end

        % Value changed function: ObjStepsizeDropDown
        function ObjStepsizeDropDownValueChanged(app, event)
            value = app.ObjStepsizeDropDown.Value;
            
            if str2num(value) == 0.01
                app.stepSizeObj = 0.01;
            end
            if str2num(value) == 0.005
                app.stepSizeObj = 0.005;
            end
            if str2num(value) == 0.001
                app.stepSizeObj = 0.001;
            end
        end

        % Button pushed function: leftButtonObj
        function leftButtonObjPushed(app, event)
            fprintf(app.controlParameters.sDetLens,['1PR',num2str(app.stepSizeObj)]);
            pause(0.5) 
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            pos = getpos(pos);
            %app.ObjPos.Value = str2num(pos);
            app.state_motor.MotorPosition_mm_(3) = str2num(pos); 
            app.UITable_motor.Data = app.state_motor;
        end

        % Button pushed function: rightButtonObj
        function rightButtonObjPushed(app, event)
            fprintf(app.controlParameters.sDetLens,['1PR-',num2str(app.stepSizeObj)]);
            pause(0.5)
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            pos = getpos(pos);
            %app.ObjPos.Value = str2num(pos);
            app.state_motor.MotorPosition_mm_(3) = str2num(pos); 
            app.UITable_motor.Data = app.state_motor;
        end

        % Callback function
        function movetileButtonPushed(app, event)
            if app.start_3D==false
                app.scanindex = 1;
                app.start_3D = true;
                disp(['This is 1 tile.'])
            elseif app.scanindex <= app.scancol
                disp(['This is ',num2str(app.scanindex),' tile.'])
            elseif app.scanindex > app.scancol
                disp('all tiles are finished');
                a = app.allcheckpoints;
                save('allpoints.mat','a')
                return;
            end
            
            [xPos, yPos, zPos] = getXYZposition(app.controlParameters.MMC);
            dx = app.xyzScanArray(1,app.scanindex) - xPos; dy = app.xyzScanArray(2,app.scanindex) - yPos; dz = app.xyzScanArray(3,app.scanindex) - zPos;
            SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
            app.scanindex = app.scanindex + 1;
        end

        % Callback function
        function save_checkpointButtonPushed(app, event)
            if app.start_correct==false
                app.start_correct = true;
                app.checkpoints_index=1;
                [app.xinitPos,app.yinitPos,app.zinitPos] = getXYZposition(app.controlParameters.MMC);
                app.xinitGalvo = app.galvo_XSlider.Value;
                app.yinitGalvo = app.galvo_YSlider.Value;
                app.zinitGalvo = app.galvo_ZSlider.Value;
                start_z_position = app.controlParameters.MMC.getYPosition();
                app.checkpoints_valuelist = zeros(app.Check_points.Value,5);
                app.zstacksposs = zeros(app.num_zstacks.Value,1);
                for i =1:app.num_zstacks.Value
                    app.zstacksposs(i,:) = start_z_position + (i-1)*app.zstack_distanceum.Value;
                end
                app.checkpoints_valuelist(:,1) = floor(linspace(app.zstacksposs(1),app.zstacksposs(end),app.Check_points.Value));
                pos_obj = posfind(app.controlParameters.sDetLens);            
                app.checkpoints_valuelist(app.checkpoints_index,2) = str2num(pos_obj);
                app.checkpoints_valuelist(app.checkpoints_index,3) = app.galvo_XSlider.Value;
                app.checkpoints_valuelist(app.checkpoints_index,4) = app.galvo_YSlider.Value;
                app.checkpoints_valuelist(app.checkpoints_index,5) = app.galvo_ZSlider.Value;
                app.checkpoints_index = app.checkpoints_index + 1;
            end
            if app.checkpoints_index > app.Check_points.Value
                app.allcheckpoints{app.scanindex-1} = app.checkpoints_valuelist;
                [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                dy = app.yinitPos - yPos;
                dx = app.xinitPos - xPos;
                dz = app.zinitPos - zPos;                
                SetRelativePos(app.controlParameters.MMC,0,dy,0);  
                disp('move to the next tile');
                app.start_correct = false;
                app.galvo_XSlider.Value = app.xinitGalvo;app.galvo_Xview.Value = app.xinitGalvo;app.galvo_Xvoltage=app.xinitGalvo;
                app.galvo_YSlider.Value = app.yinitGalvo;app.galvo_Yview.Value = app.yinitGalvo;app.galvo_Yvoltage=app.yinitGalvo;
                app.galvo_ZSlider.Value = app.zinitGalvo;app.galvo_Zview.Value = app.zinitGalvo;app.galvo_Zvoltage=app.zinitGalvo;
                outputSingleScan(app.controlParameters.s,[app.Shutterstate01,app.galvo_Xvoltage,app.galvo_Yvoltage,app.galvo_Zvoltage]);
                return;
            end
            pos_obj = posfind(app.controlParameters.sDetLens);            
            app.checkpoints_valuelist(app.checkpoints_index,2) = str2num(pos_obj);
            app.checkpoints_valuelist(app.checkpoints_index,3) = app.galvo_XSlider.Value;
            app.checkpoints_valuelist(app.checkpoints_index,4) = app.galvo_YSlider.Value;
            app.checkpoints_valuelist(app.checkpoints_index,5) = app.galvo_ZSlider.Value;
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            dy = app.checkpoints_valuelist(app.checkpoints_index,1) - yPos;
            SetRelativePos(app.controlParameters.MMC,0,dy,0);
            app.checkpoints_index = app.checkpoints_index + 1;
        end

        % Value changed function: LIghtpathDropDown
        function LIghtpathDropDownValueChanged(app, event)
            value = app.LIghtpathDropDown.Value;
            if value == 'path1'
                app.current_path = 1;
                app.Shutterstate1 = 4;app.Shutterstate2 = 0;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                
                %disp(1)
            elseif value == 'path2'
                app.current_path = 2;
                app.Shutterstate1 = 0;app.Shutterstate2 = 4;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                
            end
        end

        % Cell edit callback: UITable_afg
        function UITable_afgCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            %app.state_afg.Middle_mv_(1)
            %disp(indices);disp(newData);
%             if indices(1) == 1
%                 if indices(2) == 2
%                     app.state_afg.Middle_mv_(1) = newData;
%                     fwrite(app.controlParameters.afg, ':source1:function DC');
%                     fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(newData),'mv']);
%                     fwrite(app.controlParameters.afg, ':output1 on;');
%                 elseif indices(2) == 3
%                     app.state_afg.Top_mv_(1) = newData;
%                 elseif indices(2) == 4
%                     app.state_afg.Bottom_mv_(1) = newData;
%                 end
%             end
%             if indices(1) == 2
%                 if indices(2) == 2
%                     app.state_afg.Middle_mv_(2) = newData;
%                     fwrite(app.controlParameters.afg, ':source1:function DC');
%                     fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(newData),'mv']);
%                     fwrite(app.controlParameters.afg, ':output2 on;');
%                 elseif indices(2) == 3
%                     app.state_afg.Top_mv_(2) = newData;
%                 elseif indices(2) == 4
%                     app.state_afg.Bottom_mv_(2) = newData;
%                 end
%             end
            
            app.state_afg{indices(1),indices(2)} = newData;
            app.state_afg;
        end

        % Value changed function: AFG_scanButton
        function AFG_scanButtonValueChanged(app, event)
            value = app.AFG_scanButton.Value;
            if any([app.state_afg.Top_mv_]>2000)
                disp('voltage is too high.');
                return;
            elseif any([app.state_afg.Bottom_mv_]<-2000)
                disp('voltage is too low.');
                return;
            elseif any([app.state_afg.Bottom_mv_]>[app.state_afg.Top_mv_])
                disp('Bottom is larger than top.');
                return;
            end
            
            index=0;
            if convertCharsToStrings(app.Lightwavelight.Value) == 'None'
                f=msgbox('Choose a wavelength.','warning');
                return;
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                index = 1;
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                index = 2;
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                index = 3;
            end
            if value == 1
                
                if app.current_path == 1
                    
                    SetAfgRamp(app.controlParameters.afg,app.state_afg.Bottom_mv_(index),app.state_afg.Top_mv_(index),600,50,1);
                    %fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.state_afg.Offset_mv_(index)),'mv']);
                    fwrite(app.controlParameters.afg, ':output1 on;');
                    
                else
                    SetAfgRamp(app.controlParameters.afg,app.state_afg.Bottom_mv_(index+3),app.state_afg.Top_mv_(index+3),600,50,2);
                    fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(index+3)),'mv']);
                    fwrite(app.controlParameters.afg, ':output2 on;');
                end
                
            else
                if app.current_path == 1
                    %fwrite(app.controlParameters.afg, ':output1 off;'); 
                    fwrite(app.controlParameters.afg, ':source1:function DC');
                    fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str((app.state_afg.Bottom_mv_(index) + app.state_afg.Top_mv_(index))/2),'mv']);
                    disp((app.state_afg.Bottom_mv_(index) + app.state_afg.Top_mv_(index))/2);
                    fwrite(app.controlParameters.afg, ':output1 on;');
                else
                    %fwrite(app.controlParameters.afg, ':output2 off;'); 
                    fwrite(app.controlParameters.afg, ':source2:function DC');
                    fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(index+3)),'mv']);
                    fwrite(app.controlParameters.afg, ':output2 on;');
                end
                
            end
        end

        % Cell edit callback: UITable_motor
        function UITable_motorCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if abs(newData)>10 || abs(newData)<2
                error('new data is too high or too low')
            end
            if indices(1) == 1
                
                fprintf(app.controlParameters.sExtLens1,['1PA',num2str(newData)]);
            elseif indices(1) == 2
                fprintf(app.controlParameters.sExtLens2,['1PA',num2str(newData)]);
            end
        end

        % Cell edit callback: UITable_path
        function UITable_pathCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if abs(newData)>2
                error('the vlotage is too high')
            end
            if indices(1) == 1
                if indices(2) == 2
                    app.state_path.Galvo_X(1) = newData;
                    app.galvo_XSlider.Value = newData;
                elseif indices(2) == 3
                    app.state_path.Galvo_Y(1) = newData;
                    app.galvo_YSlider.Value = newData;
                elseif indices(2) == 4
                    app.state_path.Galvo_Z(1) = newData;
                    app.galvo_ZSlider.Value = newData;
                end
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            end
            if indices(1) == 2
                if indices(2) == 2
                    app.state_path.Galvo_X(2) = newData;
                    app.galvo_XSlider.Value = newData;
                elseif indices(2) == 3
                    app.state_path.Galvo_Y(2) = newData;
                    app.galvo_YSlider.Value = newData;
                elseif indices(2) == 4
                    app.state_path.Galvo_Z(2) = newData;
                    app.galvo_ZSlider.Value = newData;
                end
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            end
            
        end

        % Cell edit callback: UITable_offset
        function UITable_offsetCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.state_offset{indices(1),indices(2)} = newData;
            app.state_offset
        end

        % Button pushed function: SaveImagetwopathButton
        function SaveImagetwopathButtonPushed(app, event)
            if app.savepathEditField.Value == ""
                disp('Please set the saving directory.')
                return;
            end
            app.saveImage_twopath = true;
        end

        % Button pushed function: ImagesavingdirectoryButton
        function ImagesavingdirectoryButtonPushed(app, event)
            path = uigetdir('Z:/','Image saving directory');
            app.dirname = path;
            app.savepathEditField.Value = path;

        end

        % Button pushed function: savecheckpointsButton
        function savecheckpointsButtonPushed(app, event)
            if app.VerticaltileEditField.Value == 0 || app.HorizontaltileEditField.Value == 0 || app.CheckPointsEditField.Value==0
                f=msgbox('please set necessary parameters.','warning');
                return;
            end

            if app.movetotileSpinner.Value == 0
                f=msgbox('please move to the first tile.','warning');
                return;
            end

            if app.startofeachtileEditField.Value > app.endofeachtileEditField.Value
                f=msgbox('the start of tile should be deep inside the sample.','warning');
                return;
            end
            if convertCharsToStrings(app.Lightwavelight.Value) == 'None'
                f=msgbox('Please choose laser.','warning');
                return;
            end
            tile_index = app.movetotileSpinner.Value;
            %checkpoints_index = app.movecheckpoints1x6Spinner.Value;
            
%             app.checkpoints_multipletile(tile_index,checkpoints_index).current_path = app.current_path;
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X = app.state_path.Galvo_X(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y = app.state_path.Galvo_Y(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z = app.state_path.Galvo_Z(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens = app.state_motor.MotorPosition_mm_(app.current_path);
%             app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens = app.state_motor.MotorPosition_mm_(3);
            %app.checkpoints_multipletile(tile_index,checkpoints_index).wavelength = app.Lightwavelight.Value;
            app.checkpoints_multipletile(tile_index,1).current_path = app.current_path;
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.checkpoints_multipletile(tile_index,1).xPos = xPos;
            app.checkpoints_multipletile(tile_index,1).yPos = yPos;
            app.checkpoints_multipletile(tile_index,1).zPos = zPos;
            %app.checkpoints_multipletile(tile_index,checkpoints_index).saved = true;
            app.checkpoints_multipletile(tile_index,1).start_pos = round(app.startofeachtileEditField.Value,-1);
            app.checkpoints_multipletile(tile_index,1).end_pos = round(app.endofeachtileEditField.Value,-1);
            app.checkpoints_multipletile(tile_index,1).saved = true;
%             if convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).saved_488nm = true;
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X_488nm = app.state_path.Galvo_X(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y_488nm = app.state_path.Galvo_Y(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z_488nm = app.state_path.Galvo_Z(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens_488nm = app.state_motor.MotorPosition_mm_(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens_488nm = app.state_motor.MotorPosition_mm_(3);
%             elseif convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).saved_561nm = true;
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X_561nm = app.state_path.Galvo_X(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y_561nm = app.state_path.Galvo_Y(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z_561nm = app.state_path.Galvo_Z(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens_561nm = app.state_motor.MotorPosition_mm_(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens_561nm = app.state_motor.MotorPosition_mm_(3);
%                 %app.checkpoints_multipletile(tile_index,checkpoints_index).wavelength = app.Lightwavelight.Value;
%             elseif convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).saved_640nm = true;
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_X_640nm = app.state_path.Galvo_X(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Y_640nm = app.state_path.Galvo_Y(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Galvo_Z_640nm = app.state_path.Galvo_Z(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Excitionlens_640nm = app.state_motor.MotorPosition_mm_(app.current_path);
%                 app.checkpoints_multipletile(tile_index,checkpoints_index).Detectionlens_640nm = app.state_motor.MotorPosition_mm_(3);
%             end


            % check if all checkpoints are saved
            saved_total = 0;
            for i = 1:size(app.xyzScanArray,2)
                if app.checkpoints_multipletile(i,1).saved == true
                    saved_total = saved_total + 1; 
                end
            end
            
            save_all= 'No';
            if saved_total == size(app.xyzScanArray,2)
                save_all = "Yes";
                local_variable = app.checkpoints_multipletile;
                save('scanpattern.mat','local_variable');
            end

            
            %char_text = 'Tile '+ char(num2str(tile_index)) + '/' + char(num2str(size(app.xyzScanArray,2)))+'   '+char(num2str(save));
            %text_str = "Tile: " + num2str(tile_index) + '/' + num2str(size(app.xyzScanArray,2)) + " save status:" + num2str(save) + '   save all checkpoints? ' + save_all;
            text = ["Tile: " + num2str(tile_index) + '/' + num2str(size(app.xyzScanArray,2)) + ' current tile' + " save status:" + num2str(app.checkpoints_multipletile(tile_index,1).saved) + '   save all checkpoints? ' + save_all];
            
            start_min = min([app.checkpoints_multipletile(1:tile_index,1).start_pos]);
            end_max = max([app.checkpoints_multipletile(1:tile_index,1).end_pos]);
            text_maxminpos = ['min start pos: ',num2str(start_min),' max end pos: ',num2str(end_max)];
            %app.StateofTilesEditField.Value  = text_str;
            app.TileStatesTextArea.Value{1} = char(text);
            app.TileStatesTextArea.Value{2} = char(text_maxminpos);
        end

        % Button pushed function: saveButton_3
        function saveButton_3Pushed(app, event)
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.startofeachtileEditField.Value = yPos;
        end

        % Button pushed function: saveButton_4
        function saveButton_4Pushed(app, event)
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            if yPos <= (app.startofeachtileEditField.Value + 5)
                f=msgbox('the coordinate of end pos should be larger than start.','warning');
                return;
            end
            app.endofeachtileEditField.Value = yPos;
        end

        % Button pushed function: createscanpatterncenterButton
        function createscanpatterncenterButtonPushed(app, event)
            text = {['Take multitile images? ',num2str(app.VerticaltileEditField.Value),' by ',num2str(app.HorizontaltileEditField.Value)]};
            answer = questdlg(text,'light sheet microscope','Yes','No','Cancel','Cancel');
            if strcmp(answer,'Yes')
                app.movetotileSpinner.Value = 0;
                app.StatusLamp.Color = [0 1 0];
                app.xyzScanArray_status = true;
                disp('new scan');
                
            elseif strcmp(answer,'No')
                disp('old');
                app.StatusLamp.Color = [1 0 0];
                app.xyzScanArray_status = false;
                
                return;
            elseif strcmp(answer,'Cancel')
                disp('Cancel.');
                return;
            end
            pixelSizeUm = 6.5;
            tubeLensFocalDistance = 180; %mm
            objectiveDesignedTubeLensFocal = 180; %mm
            objectiveMag = 10;overlap = 15;
            downSampleFactor = 1;
            effectivePixelSize = (pixelSizeUm/objectiveMag)*(objectiveDesignedTubeLensFocal/tubeLensFocalDistance)*downSampleFactor;
            stepSizeInLateralScan = effectivePixelSize*(2048/downSampleFactor)*(100-overlap)/100;
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.xinitPos=xPos;
            app.yinitPos=yPos;
            app.zinitPos=zPos;
            [app.xyzScanArray]=createScanpattern(xPos,yPos,zPos,app.VerticaltileEditField.Value,app.HorizontaltileEditField.Value,stepSizeInLateralScan);           
            a=app.xyzScanArray;
            [m,tile_num]=size(app.xyzScanArray);
            % initialize all checkpoints
            for i=1:tile_num
                app.checkpoints_multipletile(i,1).saved_488nm=false;
                app.checkpoints_multipletile(i,1).saved_561nm=false;
                app.checkpoints_multipletile(i,1).saved_640nm=false;
                app.checkpoints_multipletile(i,1).tile_xpos=app.xyzScanArray(1,i);
                app.checkpoints_multipletile(i,1).tile_ypos=app.xyzScanArray(2,i);
                app.checkpoints_multipletile(i,1).tile_zpos=app.xyzScanArray(3,i);
            end
            %app.xyzScanArray_status = true;
            
        end

        % Value changed function: movetotileSpinner
        function movetotileSpinnerValueChanged(app, event)
            value = app.movetotileSpinner.Value;
            if value == 1
                % do nothing
            elseif app.startofeachtileEditField.Value == 0 || app.endofeachtileEditField.Value == 0
                app.movetotileSpinner.Value = 0;
                f=msgbox('Please save the start and end points.','warning');
                return;
            elseif abs(app.startofeachtileEditField.Value-app.endofeachtileEditField.Value)<100
                app.movetotileSpinner.Value = 0;
                f=msgbox('The distance between start and end point is too small.','warning');
                return;
            end
            if app.xyzScanArray_status == false
                app.movetotileSpinner.Value = 0;
                f=msgbox('please run create scan pattern to define position of each tile.','warning');
                return;
            end
            if value < 1
                app.movetotileSpinner.Value=1;
                return;
            elseif value>size(app.xyzScanArray,2)
                app.movetotileSpinner.Value=size(app.xyzScanArray,2);
                return;
            end
            app.tile_index = app.movetotileSpinner.Value;
            [xPos, yPos, zPos] = getXYZposition(app.controlParameters.MMC);
            dx = app.xyzScanArray(1,app.tile_index) - xPos; dy = app.xyzScanArray(2,app.tile_index) - yPos; dz = app.xyzScanArray(3,app.tile_index) - zPos;
            SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
        end

        % Button pushed function: startimagealltilesButton
        function startimagealltilesButtonPushed(app, event)
            if app.dirname == ""
                f=msgbox('Please set the save directory.','warning');
                return;
            end
            if app.W561nmCheckBox.Value == 0 && app.W488nmCheckBox.Value==0 && app.W640nmCheckBox.Value==0
                f=msgbox('Please choose at least one color.','warning');
                return;
            end
            %% for temporary debug
            fwrite(app.controlParameters.afg, ':source2:function DC');
            fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.afg2_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output2 on;');
            %% 
            saved_total = 0;
            for i = 1:size(app.xyzScanArray,2)
                if app.checkpoints_multipletile(i,1).saved == true
                    saved_total = saved_total + 1;
                else
                    disp(['the tile ',num2str(i),' is not saved.']);
                    return;
                end
            end   
            
            if app.TileStatesTextArea.Value{1}(end) ~= 's'
                f=msgbox('save all tiles.','warning');
                return;
            end

            app.waitbar = uifigure;
            app.wait_status = uiprogressdlg(app.waitbar,'Title','Take images','Message','Begining','Cancelable','on');
            
            % start camera
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            %checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
            pause(2);
            zero_image = uint16(zeros(2048,2048));
            try
                for tile_index = 1:size(app.xyzScanArray,2)
                    disp(['current tile nums: ',num2str(tile_index)]);
                    app.controlParameters.MMC.stopSequenceAcquisition;
                    app.controlParameters.MMC.clearCircularBuffer;
                    numOfImages = 1000000;
                    intervalMs = 0;
                    stopOnOverflow = false;
                    app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
                    app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
                    % open shutter
                    
                    outputSingleScan(app.controlParameters.s1,[4,0,0,0]);
                    
                    SetAfgRamp(app.controlParameters.afg,app.state_afg.Bottom_mv_(2),app.state_afg.Top_mv_(2),600,50,1);
                    %fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                    fwrite(app.controlParameters.afg, ':output1 on;');
                    
                    pause(1);
                    if app.wait_status.CancelRequested
                        break;
                    end
                    if app.W488nmCheckBox.Value == 1
                        value = app.Power_488EditField.Value;
                        if value > 20
                            value = 20;
                        elseif value < 0
                            value = 0;
                        end
                        for ii = 1:numel(app.allLasers)
                            ChangePowerObis(app.allLasers(ii).serialPort,value,app.allLasers(ii).maxPower);
                        end
                        SwitchOnOffObis(app.allLasers(1).serialPort,1);
                        SwitchOnOffObis(app.allLasers(2).serialPort,0);
                        SwitchOnOffObis(app.allLasers(3).serialPort,0);
                        outputSingleScan(app.controlParameters.s1,[4,app.Ch_488_galvoxEditField.Value,app.Ch_488_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                        fprintf(app.controlParameters.sFWDet, 'pos=2');
                        pause(10);
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        dx = app.checkpoints_multipletile(tile_index,1).xPos-xPos;
                        dy = app.checkpoints_multipletile(tile_index,1).yPos-yPos;
                        dz = app.checkpoints_multipletile(tile_index,1).zPos-zPos;
                        SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                        app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',app.Exposure_488EditField.Value);

                        
                        saving_dir_488nm = [app.dirname,'/','488nm'];
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        firstaxis = num2str(round(xPos+30000,-1)*10,'%06d');
                        secondaxis = num2str(round(zPos+30000,-1)*10,'%06d');
                        filefoldername = [saving_dir_488nm,'/',firstaxis,'/',firstaxis,'_',secondaxis,'/'];
                        if ~exist(filefoldername)
                            mkdir(filefoldername)
                        end
                        % start taking images
                        
                        
                        positions = round(app.checkpoints_multipletile(tile_index,1).start_pos:app.zstack_distanceum.Value:app.checkpoints_multipletile(tile_index,1).end_pos);
                        fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.nm_D488.Value)])
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        
                        for i=1:numel(positions)
                            
                            
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            %axis1 = num2str(round(xPos)*10);
                            %axis2 = num2str(round(zPos)*10);
                            thirdaxis = num2str(round(yPos+30000)*10,'%06d');
                            SetRelativePos(app.controlParameters.MMC,0,positions(i)-yPos,0);
                            
                            if mod(i,100)==0
                                fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.nm_D488.Value)])
                                dynamic_focus(app,'488');
                                disp('autofocused.')
                                app.controlParameters.MMC.stopSequenceAcquisition;
                                app.controlParameters.MMC.clearCircularBuffer;
                                numOfImages = 1000000;
                                intervalMs = 0;
                                stopOnOverflow = false;
                                %app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',150);
                                app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow); 
                                pause(0.8)
                            end
                            
                            
                            % save images
                            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                                if app.controlParameters.MMC.getRemainingImageCount()>0
                                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                                    width = app.controlParameters.MMC.getImageWidth();
                                    height = app.controlParameters.MMC.getImageHeight();
                                    pixelType = 'uint16';
                                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                                    Image = rot90(image);
                                    image_path = [filefoldername,'/',firstaxis,'_',secondaxis,'_',thirdaxis,'.tiff'];
                                    imwrite(Image,image_path);
                                    break;
                                end
                            end
                            % update waitbar
                            app.wait_status.Value = i/numel(positions);
                            app.wait_status.Message = ['Tile ',num2str(tile_index),'/',num2str(size(app.xyzScanArray,2)),' 488nm ',' Images ',num2str(i),'/',num2str(numel(positions))];
                            if app.wait_status.CancelRequested
                                break;
                            end
                        end 
                        % add zero images
                        if app.checkpoints_multipletile(tile_index,1).current_path == 1
                            outputSingleScan(app.controlParameters.s1,[0,0,0,0])                             
                        end
                        start_min = min([app.checkpoints_multipletile(:).start_pos]);
                        end_max = max([app.checkpoints_multipletile(:).end_pos]);
                        for index = start_min:app.zstack_distanceum.Value:end_max
                            if ~exist([filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'],'file')
                                % add zero images
                                imwrite(zero_image,[filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'])
                            else
                                continue;
                            end
                        end
                    end
                    if app.wait_status.CancelRequested
                        break;
                    end
                    if app.W561nmCheckBox.Value == 1
                        
                        value = app.Power_561EditField.Value;
                        if value > 20
                            value = 20;
                        elseif value < 0
                            value = 0;
                        end
                        for ii = 1:numel(app.allLasers)
                            ChangePowerObis(app.allLasers(ii).serialPort,value,app.allLasers(ii).maxPower);
                        end
                        SwitchOnOffObis(app.allLasers(1).serialPort,0);
                        SwitchOnOffObis(app.allLasers(2).serialPort,1);
                        SwitchOnOffObis(app.allLasers(3).serialPort,0);
                        fprintf(app.controlParameters.sFWDet, 'pos=3');
                        outputSingleScan(app.controlParameters.s1,[4,app.Ch_561_galvoxEditField.Value,app.Ch_561_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                        pause(10);
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        dx = app.checkpoints_multipletile(tile_index,1).xPos-xPos;
                        dy = app.checkpoints_multipletile(tile_index,1).yPos-yPos;
                        dz = app.checkpoints_multipletile(tile_index,1).zPos-zPos;
                        SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                        app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',app.Exposure_561EditField.Value);
                        
                        saving_dir_561nm = [app.dirname,'/','561nm'];
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        firstaxis = num2str(round(xPos+30000,-1)*10,'%06d');
                        secondaxis = num2str(round(zPos+30000,-1)*10,'%06d');
                        filefoldername = [saving_dir_561nm,'/',firstaxis,'/',firstaxis,'_',secondaxis,'/'];
                        if ~exist(filefoldername)
                            mkdir(filefoldername)
                        end
                        % start taking images
                        
                        
                        positions = round(app.checkpoints_multipletile(tile_index,1).start_pos:app.zstack_distanceum.Value:app.checkpoints_multipletile(tile_index,1).end_pos);
                        fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.nm_D561.Value)])
                        for i=1:numel(positions)
                               
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            %axis1 = num2str(round(xPos)*10);
                            %axis2 = num2str(round(zPos)*10);
                            thirdaxis = num2str(round(yPos+30000)*10,'%06d');
                            SetRelativePos(app.controlParameters.MMC,0,positions(i)-yPos,0);
                            
                            if mod(i,100)==0
                                fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.nm_D561.Value)])
                                dynamic_focus(app,'561');
                                disp('autofocused.');
                                app.controlParameters.MMC.stopSequenceAcquisition;
                                app.controlParameters.MMC.clearCircularBuffer;
                                numOfImages = 1000000;
                                intervalMs = 0;
                                stopOnOverflow = false;
                                %app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',150);
                                app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow); 
                                pause(0.8)
                            end
                            
                            % save images
                            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                                if app.controlParameters.MMC.getRemainingImageCount()>0
                                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                                    width = app.controlParameters.MMC.getImageWidth();
                                    height = app.controlParameters.MMC.getImageHeight();
                                    pixelType = 'uint16';
                                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                                    Image = rot90(image);
                                    image_path = [filefoldername,'/',firstaxis,'_',secondaxis,'_',thirdaxis,'.tiff'];
                                    imwrite(Image,image_path);
                                    break;
                                end
                            end
                            % update waitbar
                            app.wait_status.Value = i/numel(positions);
                            app.wait_status.Message = ['Tile ',num2str(tile_index),'/',num2str(size(app.xyzScanArray,2)),' 561nm ',' Images ',num2str(i),'/',num2str(numel(positions))];
                            if app.wait_status.CancelRequested
                                break;
                            end
                        end
                        % add zero images
                        if app.checkpoints_multipletile(tile_index,1).current_path == 1
                            outputSingleScan(app.controlParameters.s1,[0,0,0,0]);
                        elseif app.checkpoints_multipletile(tile_index,1).current_path == 2
                            a=1;
                        end
                        
                        start_min = min([app.checkpoints_multipletile(:).start_pos]);
                        end_max = max([app.checkpoints_multipletile(:).end_pos]);
                        for index = start_min:app.zstack_distanceum.Value:end_max
                            if ~exist([filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'],'file')
                                % add zero images
                                imwrite(zero_image,[filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'])
                            else
                                continue;
                            end
                        end
                    end
                    if app.wait_status.CancelRequested
                        break;
                    end
                    if app.W640nmCheckBox.Value == 1
                        
                        value = app.Power_640EditField.Value;
                        if value > 20
                            value = 20;
                        elseif value < 0
                            value = 0;
                        end
                        for ii = 1:numel(app.allLasers)
                            ChangePowerObis(app.allLasers(ii).serialPort,value,app.allLasers(ii).maxPower);
                        end
                        SwitchOnOffObis(app.allLasers(1).serialPort,0);
                        SwitchOnOffObis(app.allLasers(2).serialPort,0);
                        SwitchOnOffObis(app.allLasers(3).serialPort,1);
                        fprintf(app.controlParameters.sFWDet, 'pos=4');
                        outputSingleScan(app.controlParameters.s1,[4,app.Ch_640_galvoxEditField.Value,app.Ch_640_galvoyEditField.Value,app.state_path.Galvo_Z(1)]);
                        pause(10);
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        dx = app.checkpoints_multipletile(tile_index,1).xPos-xPos;
                        dy = app.checkpoints_multipletile(tile_index,1).yPos-yPos;
                        dz = app.checkpoints_multipletile(tile_index,1).zPos-zPos;
                        SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                        
                        app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',app.Exposure_640EditField.Value);
                        saving_dir_640nm = [app.dirname,'/','640nm'];
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        firstaxis = num2str(round(xPos+30000,-1)*10,'%06d');
                        secondaxis = num2str(round(zPos+30000,-1)*10,'%06d');
                        filefoldername = [saving_dir_640nm,'/',firstaxis,'/',firstaxis,'_',secondaxis,'/'];
                        if ~exist(filefoldername)
                            mkdir(filefoldername)
                        end
                        % start taking images
                        
                        
                        positions = round(app.checkpoints_multipletile(tile_index,1).start_pos:app.zstack_distanceum.Value:app.checkpoints_multipletile(tile_index,1).end_pos);
                        [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                        fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.nm_D640.Value)])
                        for i=1:numel(positions)
                              
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            %axis1 = num2str(round(xPos)*10);
                            %axis2 = num2str(round(zPos)*10);
                            thirdaxis = num2str(round(yPos+30000)*10,'%06d');
                            SetRelativePos(app.controlParameters.MMC,0,positions(i)-yPos,0);
                            
                            if mod(i,100)==0
                                fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.nm_D640.Value)])
                                dynamic_focus(app,'640');
                                disp('autofocused.');
                                app.controlParameters.MMC.stopSequenceAcquisition;
                                app.controlParameters.MMC.clearCircularBuffer;
                                numOfImages = 1000000;
                                intervalMs = 0;
                                stopOnOverflow = false;
                                %app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',150);
                                app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow); 
                                pause(0.8)
                            end
                                 
                            % save images
                            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                                if app.controlParameters.MMC.getRemainingImageCount()>0
                                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                                    width = app.controlParameters.MMC.getImageWidth();
                                    height = app.controlParameters.MMC.getImageHeight();
                                    pixelType = 'uint16';
                                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                                    Image = rot90(image);
                                    image_path = [filefoldername,'/',firstaxis,'_',secondaxis,'_',thirdaxis,'.tiff'];
                                    imwrite(Image,image_path);
                                    break;
                                end
                            end
                            % update waitbar
                            app.wait_status.Value = i/numel(positions);
                            app.wait_status.Message = ['Tile ',num2str(tile_index),'/',num2str(size(app.xyzScanArray,2)),' 640nm ',' Images ',num2str(i),'/',num2str(numel(positions))];
                            if app.wait_status.CancelRequested
                                break;
                            end
                        end
                        % add zero images
                        if app.checkpoints_multipletile(tile_index,1).current_path == 1
                            outputSingleScan(app.controlParameters.s1,[0,0,0,0])                               
                        end
                        start_min = min([app.checkpoints_multipletile(:).start_pos]);
                        end_max = max([app.checkpoints_multipletile(:).end_pos]);
                        for index = start_min:app.zstack_distanceum.Value:end_max
                            if ~exist([filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'],'file')
                                % add zero images
                                imwrite(zero_image,[filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'])
                            else
                                continue;
                            end
                        end
                    end
                    if app.wait_status.CancelRequested
                        break;
                    end
                end
                SwitchOnOffObis(app.allLasers(1).serialPort,0);
                SwitchOnOffObis(app.allLasers(2).serialPort,0);
                SwitchOnOffObis(app.allLasers(3).serialPort,0);
                [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                dx = app.xinitPos-xPos;dy=app.yinitPos-yPos;dz=app.zinitPos-zPos;
                SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                app.controlParameters.MMC.stopSequenceAcquisition;
                app.controlParameters.MMC.clearCircularBuffer;
                fwrite(app.controlParameters.afg, ':source1:function DC');
                fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.state_afg.Offset_mv_(1)),'mv']);
                fwrite(app.controlParameters.afg, ':output1 off;');
                fwrite(app.controlParameters.afg, ':source2:function DC');
                fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(1)),'mv']);
                fwrite(app.controlParameters.afg, ':output2 off;');
                close(app.waitbar);
                close(app.wait_status);
                %sendmail('cli38@ncsu.edu','The process is complete.','')
            catch err
                app.controlParameters.MMC.stopSequenceAcquisition;
                app.controlParameters.MMC.clearCircularBuffer;
                disp(err.message);
                
                SwitchOnOffObis(app.allLasers(1).serialPort,0);
                SwitchOnOffObis(app.allLasers(2).serialPort,0);
                SwitchOnOffObis(app.allLasers(3).serialPort,0);
                close(app.waitbar);
                close(app.wait_status);
                %Sendmail('cli38@ncsu.edu','error in the process.',err.message)
            end
        end

        % Button pushed function: saveoffsetfordifferentcolorButton
        function saveoffsetfordifferentcolorButtonPushed(app, event)
            if app.current_path == 1
                if convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                    app.state_offset.Galvo_X(1) = app.galvo_XSlider.Value;
                    app.state_offset.Galvo_Y(1) = app.galvo_YSlider.Value;
                    %app.state_offset.Galvo_Z(1) = app.galvo_ZSlider.Value;
                    app.state_offset.Detectionlens(1) = app.state_motor.MotorPosition_mm_(3);
                    app.state_offset.Excitionlens(1) = app.state_motor.MotorPosition_mm_(1);
                end
                if convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                    app.state_offset.Galvo_X(2) = app.galvo_XSlider.Value;
                    app.state_offset.Galvo_Y(2) = app.galvo_YSlider.Value;
                    %app.state_offset.Galvo_Z(2) = app.galvo_ZSlider.Value;
                    app.state_offset.Detectionlens(2) = app.state_motor.MotorPosition_mm_(3);
                    app.state_offset.Excitionlens(2) = app.state_motor.MotorPosition_mm_(1);
                end
                if convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                    app.state_offset.Galvo_X(3) = app.galvo_XSlider.Value;
                    app.state_offset.Galvo_Y(3) = app.galvo_YSlider.Value;
                    %app.state_offset.Galvo_Z(3) = app.galvo_ZSlider.Value;
                    app.state_offset.Detectionlens(3) = app.state_motor.MotorPosition_mm_(3);
                    app.state_offset.Excitionlens(3) = app.state_motor.MotorPosition_mm_(1);
                end
            else 
                if convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                    app.state_offset.Galvo_X(4) = app.galvo_XSlider.Value;
                    app.state_offset.Galvo_Y(4) = app.galvo_YSlider.Value;
                    %app.state_offset.Galvo_Z(4) = app.galvo_ZSlider.Value;
                    app.state_offset.Detectionlens(4) = app.state_motor.MotorPosition_mm_(3);
                    app.state_offset.Excitionlens(4) = app.state_motor.MotorPosition_mm_(2);
                end
                if convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                    app.state_offset.Galvo_X(5) = app.galvo_XSlider.Value;
                    app.state_offset.Galvo_Y(5) = app.galvo_YSlider.Value;
                    %app.state_offset.Galvo_Z(5) = app.galvo_ZSlider.Value;
                    app.state_offset.Detectionlens(5) = app.state_motor.MotorPosition_mm_(3);
                    app.state_offset.Excitionlens(5) = app.state_motor.MotorPosition_mm_(2);
                end
                if convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                    app.state_offset.Galvo_X(6) = app.galvo_XSlider.Value;
                    app.state_offset.Galvo_Y(6) = app.galvo_YSlider.Value;
                    %app.state_offset.Galvo_Z(6) = app.galvo_ZSlider.Value;
                    app.state_offset.Detectionlens(6) = app.state_motor.MotorPosition_mm_(3);
                    app.state_offset.Excitionlens(6) = app.state_motor.MotorPosition_mm_(2);
                end
            end
            app.UITable_offset.Data = app.state_offset;
        end

        % Value changed function: PaintButton
        function PaintButtonValueChanged(app, event)
            value = app.PaintButton.Value;
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            % the idea is first generate a very large matrix, and put the
            % inital image in the middle and move stage to add other
            % images, and crop image with value.
            if value == 1
                [app.paint_initx,app.paint_inity,app.paint_initz]=getXYZposition(app.controlParameters.MMC);
                app.paint = figure;
                app.paint.Position = [100 100 1024 1024];
                %set(app.f,'toolbar','figure');
                %hAxes = subplot(1,1,1);
                % start camear and grap initial image;
                app.controlParameters.MMC.stopSequenceAcquisition;
                app.controlParameters.MMC.clearCircularBuffer;
                app.controlParameters.MMC.setCircularBufferMemoryFootprint(1024*10);
                numOfImages = 1000000;
                intervalMs = 0;
                stopOnOverflow = false;
                %app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',10);
                app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
                % save images
                while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                    if app.controlParameters.MMC.getRemainingImageCount()>0
                        image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                        width = app.controlParameters.MMC.getImageWidth();
                        height = app.controlParameters.MMC.getImageHeight();
                        pixelType = 'uint16';
                        image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                        image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                        image = rot90(image);
                        image = image(:,end:-1:1);
                        %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                        break;
                     end
                end
                 app.paint_init_image = zeros(2048*21,2048*21);
                 app.paint_init_image(2048*10+1:2048*11,2048*10+1:2048*11) = image;
                 % crop image
                 [row_,col_] = find(app.paint_init_image>0);
                 min_row = min(row_);
                 max_row = max(row_);
                 min_col = min(col_);
                 max_col = max(col_);
                 
                 image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
                 %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                 hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
                 images.roi.Circle(gca,'Center',[(min_col+max_col)/2,(min_row+max_row)/2],'Radius',200,'Color',[0 1 0],'LineWidth',3);
                 vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
                 app.controlParameters.MMC.stopSequenceAcquisition;
                 app.controlParameters.MMC.clearCircularBuffer;
            elseif value == 0
                close(app.paint);
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.Stage_step_X < 40
                f=msgbox('choose a bigger step size.','warning');
                return;
            end
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
%             if app.Stage_step_X > 1100
%                 f=msgbox('choose a smaller step size.','warning');
%                 return;
%             end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',-app.Stage_step_X,0);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',40);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);
                    new_image = new_image(:,end:-1:1);
                    %figure(2)
                    %imshow(new_image,[0,5000])
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate pixel movement
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
           
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
            
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if app.Stage_step_X < 40
                f=msgbox('choose a bigger step size.','warning');
                return;
            end
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
%             if app.Stage_step_X > 1100
%                 f=msgbox('choose a smaller step size.','warning');
%                 return;
%             end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',app.Stage_step_X,0);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            %app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);
                    new_image = new_image(:,end:-1:1);
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate overlap
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
                 
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            %app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);   
                    new_image = new_image(:,end:-1:1);
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
                 
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',-app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            %app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);
                    new_image = new_image(:,end:-1:1);
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate overlap
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
                 
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
        end

        % Value changed function: RollScanButton
        function RollScanButtonValueChanged(app, event)
            value = app.RollScanButton.Value;
            if value == 1
                index = 2;
                fwrite(app.controlParameters.afg, ':output2 off;');
                if app.current_path == 1
                    if app.RollSlider.Value > 0
                        SetAfgRamp_roll(app.controlParameters.afg,app.RollSlider.Value*app.state_afg.Bottom_mv_(index),app.RollSlider.Value*app.state_afg.Top_mv_(index),600,50,1);
                        fwrite(app.controlParameters.afg, [':source2:phase:adjust 0deg']);
                    elseif app.RollSlider.Value == 0
                        SetAfgRamp_roll(app.controlParameters.afg,0,1,600,50,1);
                        fwrite(app.controlParameters.afg, [':source2:phase:adjust 0deg']);
                    elseif app.RollSlider.Value < 0
                        SetAfgRamp_roll(app.controlParameters.afg,-app.RollSlider.Value*app.state_afg.Bottom_mv_(index),-app.RollSlider.Value*app.state_afg.Top_mv_(index),600,50,1);
                        fwrite(app.controlParameters.afg, [':source2:phase:adjust 180deg']);
                    end
                    fwrite(controlParameters.afg, ':source1:phase:initiate');
                    fwrite(app.controlParameters.afg, ':output2 on;');
                end
            elseif value == 0
                %fwrite(app.controlParameters.afg, ':output2 off;');
                fwrite(app.controlParameters.afg, ':source2:function DC');
                fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(0),'mv']);
                
                fwrite(app.controlParameters.afg, ':output2 on;');
            end
        end

        % Value changed function: RollSlider
        function RollSliderValueChanged(app, event)
            value = app.RollSlider.Value;
            if app.RollScanButton.Value == 0
                app.RollSlider.Value = 0;
                return;
            end
            index =2;
            if value > 0
                SetAfgRamp_roll(app.controlParameters.afg,value*app.state_afg.Bottom_mv_(index),app.RollSlider.Value*app.state_afg.Top_mv_(index),600,50,1);
                fwrite(app.controlParameters.afg, [':source2:phase:adjust 0deg']);
            elseif value == 0
                return;
                %SetAfgRamp_roll(app.controlParameters.afg,0.01*app.state_afg.Bottom_mv_(index),0.01*app.state_afg.Top_mv_(index),600,50,1);
                %fwrite(afg, [':source2:phase:adjust 0deg']);
            elseif value < 0
                SetAfgRamp_roll(app.controlParameters.afg,-value*app.state_afg.Bottom_mv_(index),-value*app.state_afg.Top_mv_(index),600,50,1);
                fwrite(app.controlParameters.afg, [':source2:phase:adjust 180deg']);
            end
            fwrite(app.controlParameters.afg, ':output2 on;');
        end

        % Button pushed function: SavepaintButton
        function SavepaintButtonPushed(app, event)
            if app.dirname == ""
                f=msgbox('Please set the save directory.','warning');
                return;
            end
            random_index = num2str(floor(rand*1000000),'%06d');
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
           
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %pixelType = 'uint16';
            %image = typecast(image_cropped, pixelType);
            imwrite(uint16(image_cropped),[app.dirname,'/',random_index,'.tiff']);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'a'
                    % move to previous tile
                    app.moveprevioustile();
                case 'd'
                    % move to next tile
                    app.movenexttile();
                case 'i'
                    app.Button_5Pushed();
                case 'o'
                    app.Button_6Pushed();
                case 'k'
                    app.saveButton_3Pushed();
                case 'l'
                    app.saveButton_4Pushed();
                case 'w'
                    % move next checkpoints
                    app.movenextcheck();
                case 's'
                    % move previous checkpoints
                    app.moveprevcheck();
                case 'space'
                    app.savecheckpointsButtonPushed();
                case 'leftarrow'
                    app.leftButtonObjPushed();
                case 'rightarrow'    
                    app.rightButtonObjPushed();
            end
        end

        % Value changed function: Exposure_timeEditField
        function Exposure_timeEditFieldValueChanged(app, event)
            value = app.Exposure_timeEditField.Value;
            if value > 300
                app.exposure_time = 300;
            elseif value < 8
                app.exposure_time = 10;
            end
        end

        % Value changed function: Exposure_561EditField
        function Exposure_561EditFieldValueChanged(app, event)
            value = app.Exposure_561EditField.Value;
            
        end

        % Button pushed function: save_DButton
        function save_DButtonPushed(app, event)
            if convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                fprintf(app.controlParameters.sDetLens,'1TP?');
                pos = fscanf(app.controlParameters.sDetLens);
                pos = getpos(pos);
                app.nm_D488.Value = str2num(pos);
            end
            if convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                fprintf(app.controlParameters.sDetLens,'1TP?');
                pos = fscanf(app.controlParameters.sDetLens);
                pos = getpos(pos);
                app.nm_D561.Value = str2num(pos);
            end
            if convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                fprintf(app.controlParameters.sDetLens,'1TP?');
                pos = fscanf(app.controlParameters.sDetLens);
                pos = getpos(pos);
                app.nm_D640.Value = str2num(pos);
            end
            
        end

        % Size changed function: 
        % TakingimagesonMbyNtileswithzstacksPanel
        function TakingimagesonMbyNtileswithzstacksPanelSizeChanged(app, event)
            position = app.TakingimagesonMbyNtileswithzstacksPanel.Position;
            
        end

        % Button pushed function: AutoStartEndButton
        function AutoStartEndButtonPushed(app, event)
            auto_start_end(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ShutterButton = uibutton(app.UIFigure, 'state');
            app.ShutterButton.ValueChangedFcn = createCallbackFcn(app, @ShutterButtonValueChanged, true);
            app.ShutterButton.Text = 'Shutter';
            app.LightwavelightDropDownLabel = uilabel(app.UIFigure);
            app.LightwavelightDropDownLabel.Text = 'Light wavelight';
            app.Lightwavelight = uidropdown(app.UIFigure);
            app.Lightwavelight.Items = {'None', '488nm', '561nm', '640nm'};
            app.Lightwavelight.ValueChangedFcn = createCallbackFcn(app, @LightwavelightValueChanged, true);
            app.Lightwavelight.Value = 'None';
            app.LaserPowermWEditFieldLabel = uilabel(app.UIFigure);
            app.LaserPowermWEditFieldLabel.Text = 'Laser Power(mW)';
            app.LaserPower = uieditfield(app.UIFigure, 'numeric');
            app.LaserPower.ValueChangedFcn = createCallbackFcn(app, @LaserPowerValueChanged, true);
            app.LaserPower.Value = 2;
            app.galvo_XSliderLabel = uilabel(app.UIFigure);
            app.galvo_XSliderLabel.Text = 'galvo_X';
            app.galvo_XSlider = uislider(app.UIFigure);
            app.galvo_XSlider.Limits = [-0.4 0.4];
            app.galvo_XSlider.MajorTicks = [-0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4];
            app.galvo_XSlider.ValueChangedFcn = createCallbackFcn(app, @galvo_XSliderValueChanged, true);
            app.galvo_YSliderLabel = uilabel(app.UIFigure);
            app.galvo_YSliderLabel.Text = 'galvo_Y';
            app.galvo_YSlider = uislider(app.UIFigure);
            app.galvo_YSlider.Limits = [-0.6 0.6];
            app.galvo_YSlider.MajorTicks = [-0.6 -0.5 -0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4 0.5 0.6];
            app.galvo_YSlider.ValueChangedFcn = createCallbackFcn(app, @galvo_YSliderValueChanged, true);
            app.Reset_galvo_XYButton = uibutton(app.UIFigure, 'push');
            app.Reset_galvo_XYButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_galvo_XYButtonPushed, true);
            app.Reset_galvo_XYButton.Text = 'Reset_galvo_XY';
            app.EnableCam = uibutton(app.UIFigure, 'push');
            app.EnableCam.ButtonPushedFcn = createCallbackFcn(app, @EnableCamButtonPushed, true);
            app.EnableCam.Text = 'Enable Camera';
            app.DisableCam = uibutton(app.UIFigure, 'push');
            app.DisableCam.ButtonPushedFcn = createCallbackFcn(app, @DisableCamButtonPushed, true);
            app.DisableCam.Text = 'Disable Camera';
            app.ImagecontrastLabel = uilabel(app.UIFigure);
            app.ImagecontrastLabel.Text = 'Image contrast';
            app.Imagecontrast = uieditfield(app.UIFigure, 'numeric');
            app.Imagecontrast.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastValueChanged, true);
            app.Imagecontrast.Value = 4000;
            app.FilterwheelDropDownLabel = uilabel(app.UIFigure);
            app.FilterwheelDropDownLabel.Text = 'Filter wheel';
            app.Filterwheel = uidropdown(app.UIFigure);
            app.Filterwheel.Items = {'None', 'Green', 'Orange', 'Red'};
            app.Filterwheel.ItemsData = {'None', 'Blue', 'Green', 'Red'};
            app.Filterwheel.ValueChangedFcn = createCallbackFcn(app, @FilterwheelValueChanged, true);
            app.Filterwheel.Value = 'None';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '⏫';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '⏬';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '⍟';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '⌾';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '⏪';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '⏩';
            app.SaveImage = uibutton(app.UIFigure, 'push');
            app.SaveImage.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImage.Text = 'Save Image onepath';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '↺';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '↻';
            app.Updown_stepDropDownLabel = uilabel(app.UIFigure);
            app.Updown_stepDropDownLabel.Text = 'Updown_step';
            app.Updown_stepDropDown = uidropdown(app.UIFigure);
            app.Updown_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm', '1cm'};
            app.Updown_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Updown_stepDropDownValueChanged, true);
            app.Updown_stepDropDown.Value = '10um';
            app.Inout_stepDropDownLabel = uilabel(app.UIFigure);
            app.Inout_stepDropDownLabel.Text = 'Inout_step';
            app.Inout_stepDropDown = uidropdown(app.UIFigure);
            app.Inout_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm', '1cm'};
            app.Inout_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Inout_stepDropDownValueChanged, true);
            app.Inout_stepDropDown.Value = '10um';
            app.Leftright_stepDropDownLabel = uilabel(app.UIFigure);
            app.Leftright_stepDropDownLabel.Text = 'Leftright_step';
            app.Leftright_stepDropDown = uidropdown(app.UIFigure);
            app.Leftright_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm', '1cm'};
            app.Leftright_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Leftright_stepDropDownValueChanged, true);
            app.Leftright_stepDropDown.Value = '10um';
            app.Rotation_stepDropDownLabel = uilabel(app.UIFigure);
            app.Rotation_stepDropDownLabel.Text = 'Rotation_step';
            app.Rotation_stepDropDown = uidropdown(app.UIFigure);
            app.Rotation_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm'};
            app.Rotation_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Rotation_stepDropDownValueChanged, true);
            app.Rotation_stepDropDown.Value = '10um';
            app.LIghtpathDropDownLabel = uilabel(app.UIFigure);
            app.LIghtpathDropDownLabel.Text = 'LIght path';
            app.LIghtpathDropDown = uidropdown(app.UIFigure);
            app.LIghtpathDropDown.Items = {'path1', 'path2'};
            app.LIghtpathDropDown.ValueChangedFcn = createCallbackFcn(app, @LIghtpathDropDownValueChanged, true);
            app.LIghtpathDropDown.Value = 'path1';
            app.UITable_stage = uitable(app.UIFigure);
            app.UITable_stage.ColumnName = {'Stage'; 'X'; 'Y'; 'Z'; 'T'};
            app.UITable_stage.RowName = {};
            app.UITable_path = uitable(app.UIFigure);
            app.UITable_path.ColumnName = {'Path'; 'Galvo_X'; 'Galvo_Y'; 'Galvo_Z'};
            app.UITable_path.RowName = {};
            app.UITable_path.ColumnEditable = true;
            app.UITable_path.CellEditCallback = createCallbackFcn(app, @UITable_pathCellEdit, true);
            app.UITable_motor = uitable(app.UIFigure);
            app.UITable_motor.ColumnName = {'Lens'; 'Position(mm)'};
            app.UITable_motor.RowName = {};
            app.UITable_motor.ColumnEditable = true;
            app.UITable_motor.CellEditCallback = createCallbackFcn(app, @UITable_motorCellEdit, true);
            app.UITable_afg = uitable(app.UIFigure);
            app.UITable_afg.ColumnName = {'AFG'; 'Offset(mv)'; 'Top(mv)'; 'Bottom(mv)'};
            app.UITable_afg.RowName = {};
            app.UITable_afg.ColumnEditable = true;
            app.UITable_afg.CellEditCallback = createCallbackFcn(app, @UITable_afgCellEdit, true);
            app.AFG_scanButton = uibutton(app.UIFigure, 'state');
            app.AFG_scanButton.ValueChangedFcn = createCallbackFcn(app, @AFG_scanButtonValueChanged, true);
            app.AFG_scanButton.Text = 'AFG_scan';
            app.UITable_offset = uitable(app.UIFigure);
            app.UITable_offset.ColumnName = {'Offset'; 'Galvo_X'; 'Galvo_Y'; 'Galvo_Z'; 'Detectionlens'; 'Excitionlens'};
            app.UITable_offset.RowName = {};
            app.UITable_offset.ColumnEditable = true;
            app.UITable_offset.CellEditCallback = createCallbackFcn(app, @UITable_offsetCellEdit, true);
            app.SaveImagetwopathButton = uibutton(app.UIFigure, 'push');
            app.SaveImagetwopathButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagetwopathButtonPushed, true);
            app.SaveImagetwopathButton.Text = 'Save Image twopath';
            app.ImagesavingdirectoryButton = uibutton(app.UIFigure, 'push');
            app.ImagesavingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesavingdirectoryButtonPushed, true);
            app.ImagesavingdirectoryButton.Text = 'Image saving directory';
            app.savepathEditFieldLabel = uilabel(app.UIFigure);
            app.savepathEditFieldLabel.Text = 'save path';
            app.savepathEditField = uieditfield(app.UIFigure, 'text');
            app.OffsetfordifferentchannelsPanel = uipanel(app.UIFigure);
            app.OffsetfordifferentchannelsPanel.Title = 'Offset for different channels';
            app.Ch_488_galvoxEditFieldLabel = uilabel(app.OffsetfordifferentchannelsPanel);
            app.Ch_488_galvoxEditFieldLabel.Text = 'Ch_488_galvox';
            app.Ch_488_galvoxEditField = uieditfield(app.OffsetfordifferentchannelsPanel, 'numeric');
            app.Ch_488_galvoxEditField.Value = 0.05;
            app.Ch_488_galvoyEditFieldLabel = uilabel(app.OffsetfordifferentchannelsPanel);
            app.Ch_488_galvoyEditFieldLabel.Text = 'Ch_488_galvoy';
            app.Ch_488_galvoyEditField = uieditfield(app.OffsetfordifferentchannelsPanel, 'numeric');
            app.Ch_561_galvoxEditFieldLabel = uilabel(app.OffsetfordifferentchannelsPanel);
            app.Ch_561_galvoxEditFieldLabel.Text = 'Ch_561_galvox';
            app.Ch_561_galvoxEditField = uieditfield(app.OffsetfordifferentchannelsPanel, 'numeric');
            app.Ch_561_galvoxEditField.Value = -0.2;
            app.Ch_561_galvoyEditField_2Label = uilabel(app.OffsetfordifferentchannelsPanel);
            app.Ch_561_galvoyEditField_2Label.Text = 'Ch_561_galvoy';
            app.Ch_561_galvoyEditField = uieditfield(app.OffsetfordifferentchannelsPanel, 'numeric');
            app.Ch_640_galvoxEditFieldLabel = uilabel(app.OffsetfordifferentchannelsPanel);
            app.Ch_640_galvoxEditFieldLabel.Text = 'Ch_640_galvox';
            app.Ch_640_galvoxEditField = uieditfield(app.OffsetfordifferentchannelsPanel, 'numeric');
            app.Ch_640_galvoyEditField_2Label = uilabel(app.OffsetfordifferentchannelsPanel);
            app.Ch_640_galvoyEditField_2Label.Text = 'Ch_640_galvoy';
            app.Ch_640_galvoyEditField = uieditfield(app.OffsetfordifferentchannelsPanel, 'numeric');
            app.Ch_640_galvoyEditField.Value = -0.4;
            app.TakingimagesonMbyNtileswithzstacksPanel = uipanel(app.UIFigure);
            app.TakingimagesonMbyNtileswithzstacksPanel.Title = 'Taking images on M-by-N tiles with zstacks';
            app.TakingimagesonMbyNtileswithzstacksPanel.SizeChangedFcn = createCallbackFcn(app, @TakingimagesonMbyNtileswithzstacksPanelSizeChanged, true);
            app.movetotileSpinnerLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.movetotileSpinnerLabel.Text = 'move to tile';
            app.movetotileSpinner = uispinner(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.movetotileSpinner.ValueChangedFcn = createCallbackFcn(app, @movetotileSpinnerValueChanged, true);
            app.VerticaltileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.VerticaltileEditFieldLabel.Text = 'Vertical tile ';
            app.VerticaltileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.HorizontaltileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.HorizontaltileEditFieldLabel.Text = 'Horizontal tile';
            app.HorizontaltileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.CheckPointsEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.CheckPointsEditFieldLabel.Text = 'Check Points';
            app.CheckPointsEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.CheckPointsEditField.Value = 5;
            app.savecheckpointsButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.savecheckpointsButton.ButtonPushedFcn = createCallbackFcn(app, @savecheckpointsButtonPushed, true);
            app.savecheckpointsButton.Text = 'save checkpoints';
            app.startofeachtileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.startofeachtileEditFieldLabel.Text = 'start of each tile';
            app.startofeachtileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.saveButton_3 = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.saveButton_3.ButtonPushedFcn = createCallbackFcn(app, @saveButton_3Pushed, true);
            app.saveButton_3.Text = 'save';
            app.endofeachtileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.endofeachtileEditFieldLabel.Text = 'end of each tile';
            app.endofeachtileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.saveButton_4 = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.saveButton_4.ButtonPushedFcn = createCallbackFcn(app, @saveButton_4Pushed, true);
            app.saveButton_4.Text = 'save';
            app.createscanpatterncenterButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.createscanpatterncenterButton.ButtonPushedFcn = createCallbackFcn(app, @createscanpatterncenterButtonPushed, true);
            app.createscanpatterncenterButton.Text = 'create scan pattern(center)';
            app.startimagealltilesButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.startimagealltilesButton.ButtonPushedFcn = createCallbackFcn(app, @startimagealltilesButtonPushed, true);
            app.startimagealltilesButton.Text = 'start image all tiles';
            app.W488nmCheckBox = uicheckbox(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.W488nmCheckBox.Text = 'W488nm';
            app.W561nmCheckBox = uicheckbox(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.W561nmCheckBox.Text = 'W561nm';
            app.W640nmCheckBox = uicheckbox(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.W640nmCheckBox.Text = 'W640nm';
            app.ChoosewhichcolortoimageLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.ChoosewhichcolortoimageLabel.Text = 'Choose which color to image';
            app.DeepLabel_2 = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.DeepLabel_2.Text = 'Deep';
            app.SurfaceLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.SurfaceLabel.Text = 'Surface';
            app.saveoffsetfordifferentcolorButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.saveoffsetfordifferentcolorButton.ButtonPushedFcn = createCallbackFcn(app, @saveoffsetfordifferentcolorButtonPushed, true);
            app.saveoffsetfordifferentcolorButton.Text = 'save offset for different color';
            app.StatusLampLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.GreensignalwillblockmotionexceptinzaxisLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.GreensignalwillblockmotionexceptinzaxisLabel.Text = 'Green signal will block motion except in z axis';
            app.TileStatesTextAreaLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.TileStatesTextAreaLabel.Text = 'Tile States';
            app.TileStatesTextArea = uitextarea(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.z_distanceumLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.z_distanceumLabel.Text = 'z_distance(um)';
            app.zstack_distanceum = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.zstack_distanceum.Value = 20;
            app.nm_DEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.nm_DEditFieldLabel.Text = '488nm_D';
            app.nm_D488 = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.nm_DEditField_2Label = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.nm_DEditField_2Label.Text = '561nm_D';
            app.nm_D561 = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.nm_DEditFieldLabel_2 = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.nm_DEditFieldLabel_2.Text = '640nm_D';
            app.nm_D640 = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.save_DButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.save_DButton.ButtonPushedFcn = createCallbackFcn(app, @save_DButtonPushed, true);
            app.save_DButton.Text = 'save_D';
            app.AutoStartEndButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.AutoStartEndButton.ButtonPushedFcn = createCallbackFcn(app, @AutoStartEndButtonPushed, true);
            app.AutoStartEndButton.Text = 'Auto Start End';
            app.ExcitionLensandDetectionLensPanel = uipanel(app.UIFigure);
            app.ExcitionLensandDetectionLensPanel.Title = 'Excition Lens and Detection Lens';
            app.leftButtonExt = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.leftButtonExt.ButtonPushedFcn = createCallbackFcn(app, @leftButtonExtPushed, true);
            app.leftButtonExt.Text = 'left';
            app.rightButtonExt = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.rightButtonExt.ButtonPushedFcn = createCallbackFcn(app, @rightButtonExtPushed, true);
            app.rightButtonExt.Text = 'right';
            app.leftButtonObj = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.leftButtonObj.ButtonPushedFcn = createCallbackFcn(app, @leftButtonObjPushed, true);
            app.leftButtonObj.Text = 'left';
            app.rightButtonObj = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.rightButtonObj.ButtonPushedFcn = createCallbackFcn(app, @rightButtonObjPushed, true);
            app.rightButtonObj.Text = 'right';
            app.ExtStepsizeDropDownLabel = uilabel(app.ExcitionLensandDetectionLensPanel);
            app.ExtStepsizeDropDownLabel.Text = 'Ext Step size';
            app.ExtStepsizeDropDown = uidropdown(app.ExcitionLensandDetectionLensPanel);
            app.ExtStepsizeDropDown.Items = {'0.1', '0.01', '0.001'};
            app.ExtStepsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtStepsizeDropDownValueChanged, true);
            app.ExtStepsizeDropDown.Value = '0.1';
            app.ObjStepsizeDropDownLabel = uilabel(app.ExcitionLensandDetectionLensPanel);
            app.ObjStepsizeDropDownLabel.Text = 'Obj Step size';
            app.ObjStepsizeDropDown = uidropdown(app.ExcitionLensandDetectionLensPanel);
            app.ObjStepsizeDropDown.Items = {'0.01', '0.005', '0.001'};
            app.ObjStepsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjStepsizeDropDownValueChanged, true);
            app.ObjStepsizeDropDown.Value = '0.01';
            app.PaintModePanel = uipanel(app.UIFigure);
            app.PaintModePanel.Title = 'Paint Mode';
            app.PaintButton = uibutton(app.PaintModePanel, 'state');
            app.PaintButton.ValueChangedFcn = createCallbackFcn(app, @PaintButtonValueChanged, true);
            app.PaintButton.Text = 'Paint';
            app.UpButton = uibutton(app.PaintModePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.PaintModePanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.PaintModePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.PaintModePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.SavepaintButton = uibutton(app.PaintModePanel, 'push');
            app.SavepaintButton.ButtonPushedFcn = createCallbackFcn(app, @SavepaintButtonPushed, true);
            app.SavepaintButton.Text = 'Save paint';
            app.RollSliderLabel = uilabel(app.UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.UIFigure);
            app.RollSlider.Limits = [-0.1 0.1];
            app.RollSlider.MajorTicks = [-0.1 -0.075 -0.05 -0.025 0 0.025 0.05 0.075 0.1];
            app.RollSlider.ValueChangedFcn = createCallbackFcn(app, @RollSliderValueChanged, true);
            app.RollScanButton = uibutton(app.UIFigure, 'state');
            app.RollScanButton.ValueChangedFcn = createCallbackFcn(app, @RollScanButtonValueChanged, true);
            app.RollScanButton.Text = 'Roll Scan';
            app.imageparametersPanel = uipanel(app.UIFigure);
            app.imageparametersPanel.Title = 'image parameters';
            app.Power_561EditFieldLabel = uilabel(app.imageparametersPanel);
            app.Power_561EditFieldLabel.Text = 'Power_561';
            app.Power_561EditField = uieditfield(app.imageparametersPanel, 'numeric');
            app.Power_561EditField.Value = 5;
            app.Exposure_561EditFieldLabel = uilabel(app.imageparametersPanel);
            app.Exposure_561EditFieldLabel.Text = 'Exposure_561';
            app.Exposure_561EditField = uieditfield(app.imageparametersPanel, 'numeric');
            app.Exposure_561EditField.ValueChangedFcn = createCallbackFcn(app, @Exposure_561EditFieldValueChanged, true);
            app.Exposure_561EditField.Value = 10;
            app.Power_640EditFieldLabel = uilabel(app.imageparametersPanel);
            app.Power_640EditFieldLabel.Text = 'Power_640';
            app.Power_640EditField = uieditfield(app.imageparametersPanel, 'numeric');
            app.Power_640EditField.Value = 10;
            app.Exposure_640EditFieldLabel = uilabel(app.imageparametersPanel);
            app.Exposure_640EditFieldLabel.Text = 'Exposure_640';
            app.Exposure_640EditField = uieditfield(app.imageparametersPanel, 'numeric');
            app.Exposure_640EditField.Value = 10;
            app.Power_488EditFieldLabel = uilabel(app.imageparametersPanel);
            app.Power_488EditFieldLabel.Text = 'Power_488';
            app.Power_488EditField = uieditfield(app.imageparametersPanel, 'numeric');
            app.Power_488EditField.Value = 5;
            app.Exposure_488EditFieldLabel = uilabel(app.imageparametersPanel);
            app.Exposure_488EditFieldLabel.Text = 'Exposure_488';
            app.Exposure_488EditField = uieditfield(app.imageparametersPanel, 'numeric');
            app.Exposure_488EditField.Value = 10;
            app.Exposure_timeEditFieldLabel = uilabel(app.UIFigure);
            app.Exposure_timeEditFieldLabel.Text = 'Exposure_time';
            app.Exposure_timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Exposure_timeEditField.ValueChangedFcn = createCallbackFcn(app, @Exposure_timeEditFieldValueChanged, true);
            app.Exposure_timeEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_for_lightsheet_V2_main_autofocus_auto_start_end

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end